classdef Decision_Making_Rig2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        nostim                      matlab.ui.control.CheckBox
        PatternMatchMode            matlab.ui.control.CheckBox
        text29_8                    matlab.ui.control.Label
        corr_trials_lim             matlab.ui.control.NumericEditField
        correcttrialslimitLabel     matlab.ui.control.Label
        reward_counter              matlab.ui.control.Label
        icecream_pizza              matlab.ui.control.CheckBox
        bypass                      matlab.ui.control.CheckBox
        fanmarbles                  matlab.ui.control.CheckBox
        NewStim                     matlab.ui.control.CheckBox
        Eztest                      matlab.ui.control.CheckBox
        ReverseMode                 matlab.ui.control.CheckBox
        Mixstage                    matlab.ui.control.CheckBox
        percent_stage2              matlab.ui.control.NumericEditField
        PercentStage2Label          matlab.ui.control.Label
        InitOnlyMode                matlab.ui.control.CheckBox
        rulechangecount             matlab.ui.control.NumericEditField
        RuleChangeCountLabel        matlab.ui.control.Label
        rcMode                      matlab.ui.control.CheckBox
        AudioMode                   matlab.ui.control.CheckBox
        closescreenButton           matlab.ui.control.Button
        TimeoutPunishmentCheckBox   matlab.ui.control.CheckBox
        timeout_punishment          matlab.ui.control.NumericEditField
        TimeoutPunishmentsLabel     matlab.ui.control.Label
        FollowLightMode             matlab.ui.control.CheckBox
        EasyMode                    matlab.ui.control.CheckBox
        AllBackgroundButton         matlab.ui.control.Button
        SaveSettingsButton          matlab.ui.control.Button
        LoadSettingsButton          matlab.ui.control.Button
        Panel_General               matlab.ui.container.Panel
        text29_4                    matlab.ui.control.Label
        text29_7                    matlab.ui.control.Label
        percent_left                matlab.ui.control.NumericEditField
        PercentStimLLabel           matlab.ui.control.Label
        text19_2                    matlab.ui.control.Label
        lick_time_requirement       matlab.ui.control.NumericEditField
        LickTimeRequirementLabel    matlab.ui.control.Label
        lick_count_requirement      matlab.ui.control.NumericEditField
        NumberofLicksRequiredLabel  matlab.ui.control.Label
        ex_duration                 matlab.ui.control.NumericEditField
        ExperimentDurationsLabel    matlab.ui.control.Label
        write                       matlab.ui.control.Button
        uipanel2                    matlab.ui.container.Panel
        r_pulse_C                   matlab.ui.control.EditField
        r_pulse_R                   matlab.ui.control.EditField
        valveC                      matlab.ui.control.Button
        rewardC                     matlab.ui.control.Button
        rewardR                     matlab.ui.control.Button
        r_pulse_L                   matlab.ui.control.EditField
        text4                       matlab.ui.control.Label
        valveL                      matlab.ui.control.Button
        valveR                      matlab.ui.control.Button
        rewardL                     matlab.ui.control.Button
        text36                      matlab.ui.control.Label
        text29                      matlab.ui.control.Label
        state1_count                matlab.ui.control.Label
        text26                      matlab.ui.control.Label
        notes                       matlab.ui.control.EditField
        dir                         matlab.ui.control.Button
        text19                      matlab.ui.control.Label
        animalid                    matlab.ui.control.EditField
        statenumber                 matlab.ui.control.Label
        start                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        stopper % stop button for timer
    end
    
    methods (Access = private)


        function saveState(app,fn)
            
            h = findobj(app.figure1,'Type','uicheckbox');
            xarr = zeros(length(h),1);
            for i = 1:length(h)
               xarr(i) = h(i).Value;
            end
            state.checkboxes = xarr;
            
            
             h = findobj(app.figure1,'Type','uieditfield');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.editfields = xa;
             
             h = findobj(app.figure1,'Type','uinumericeditfield');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.uinumericeditfields = xa;
             
             h = findobj(app.figure1,'Type','uiradiobutton');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.uiradiobuttons = xa;
             
             h = findobj(app.figure1,'Type','uiDropDown');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.dropdowns = xa;
             
           ps = app.figure1.Position;
           save(fn,'state','ps');
        end

        
        function loadState(app,fn)
%                return
%             load('MyAppDefaultValues.mat','state');
            load(fn);
            
            h = findobj(app.figure1,'Type','uicheckbox');
            for i = 1:length(h)
               h(i).Value = state.checkboxes(i);
            end
            
             h = findobj(app.figure1,'Type','uieditfield');
             for i = 1:length(h)
                h(i).Value = state.editfields{i};
             end
             
             h = findobj(app.figure1,'Type','uinumericeditfield');
             for i = 1:length(h)
                h(i).Value = state.uinumericeditfields{i};
             end
             
             
             h = findobj(app.figure1,'Type','uiradiobutton');
              for i = 1:length(h)
                 h(i).Value = state.uiradiobuttons{i};
              end
              
             h = findobj(app.figure1,'Type','uiDropDown');
              for i = 1:length(h)
                 h(i).Value = state.dropdowns{i};
              end
              
             app.figure1.Position = ps;

        end        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app)
            
            
              addpath(genpath(['.' filesep 'general' filesep]));
              addpath(genpath(['.' filesep 'DM' filesep]));
              addpath(genpath(['.' filesep 'SS' filesep]));

              app.loadState('gui_settings.mat');

%               app.figure1.Position = [800 500 1216 755];
              app.figure1.Name = 'yarm';
%               app.start.Enable = 'off';
              app.start.Visible = 'off';
              init(app);
              app.start.Visible = 'on';
              app.start.Enable = 'on';

        end

        % Callback function
        function airpuff_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c','d');  %r
            fprintf('%4.3f air_puff \n',toc);
        end

        % Callback function
        function airpuff_duration_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['u' round(app.airpuff_duration.Value)]);
        end

        % Callback function
        function cclock_Callback(app, event)
            global arduino;
            
            if contains(app.cclock.Text,'Start')
                fprintf(arduino,'%c','(');
                app.cclock.Text = 'Stop Camera Clock';
            else
                fprintf(arduino,'%c',')');
                app.cclock.Text = 'Start Camera Clock';
            end
        end

        % Button pushed function: dir
        function dir_Callback(app, event)
            global dirpath;
            if isempty(dirpath)
                dirpath = pwd;
            end
            ddir = uigetdir(dirpath);
            
            app.animalid.Value = [ddir '\'];
        end

        % Callback function
        function flashc_Callback(app, event)
            if app.allleds.Value
                light_Callback(1,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(1,7);
            else
            light_Callback(1,0);
            pause(0.25);
            light_Callback(1,7);  % turn off
            light_Callback(1,1);
            pause(0.25);
            light_Callback(1,7);  % turn off
            end
        end

        % Callback function
        function flashl_Callback(app, event)
            if  app.allleds.Value
                light_Callback(0,5);
                light_Callback(0,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(0,7);
            elseif app.light2.Value
                light_Callback(0,5);
                pause(0.25);
                light_Callback(0,7);
            else
                light_Callback(0,5);
                pause(0.25);
                light_Callback(0,7);  % turn off
                light_Callback(0,0);
                pause(0.25);
                light_Callback(0,7);  % turn off
                light_Callback(0,1);
                pause(0.25);
                light_Callback(0,7);  % turn off
            end
        end

        % Callback function
        function flashr_Callback(app, event)
            if  app.allleds.Value
                light_Callback(2,5);
                light_Callback(2,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(2,7);
            elseif  app.light2.Value
                light_Callback(2,5);
                pause(0.25);
                light_Callback(2,7); % turn off
            else
                light_Callback(2,5);
                pause(0.25);
                light_Callback(2,7); % turn off
                light_Callback(2,1);
                pause(0.25);
                light_Callback(2,7); % turn off
                light_Callback(2,0);
                pause(0.25);
                light_Callback(2,7);  % turn off
            end
        end

        % Callback function
        function fullout_Callback(app, event)
            global spout1pos spout2pos;
            global current_servo_position;
            
            control_spouts(spout1pos.ext,spout2pos.ext);
            current_servo_position =  [spout1pos.ext,spout2pos.ext];
        end

        % Callback function
        function fullretract_Callback(app, event)
            
            % fully retract both spouts
            global current_servo_position;
            
            control_spouts(0,0);
            current_servo_position = [0,0];
        end

        % Callback function
        function light2_Callback(app, event)
            if app.light2.Value
               app.light2.Value = 1;
               app.light8.Value = 0;
            else
               app.light2.Value = 0;
               app.light8.Value = 1;
            end
        end

        % Callback function
        function light8_Callback(app, event)
            if app.light8.Value
               app.light2.Value = 0;
               app.light8.Value = 1;
            else
               app.light2.Value = 1;
               app.light8.Value = 0;
            end
        end

        % Callback function
        function per_R_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['w' round(app.r_pulse_R.Value)]);
        end

        % Button pushed function: rewardC
        function rewardC_Callback(app, event)
            
            reward_Callback(app,'C');
        end

        % Button pushed function: rewardL
        function rewardL_Callback(app, event)
            
            reward_Callback(app,'L');
        end

        % Button pushed function: rewardR
        function rewardR_Callback(app, event)
            reward_Callback(app,'R');

        end

        % Value changed function: r_pulse_C
        function r_pulse_C_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['w' round(str2double(app.r_pulse_C.Value))]);
        end

        % Value changed function: r_pulse_L
        function r_pulse_L_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['v' round(str2double(app.r_pulse_L.Value))]);
        end

        % Value changed function: r_pulse_R
        function r_pulse_R_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['q' round(str2double(app.r_pulse_R.Value))]);
        end

        % Callback function
        function sp1ext_Callback(app, event)
            global spout1pos;
            spout1pos.ext = str2double(app.sp1ext.Value);
        end

        % Callback function
        function sp1rest_Callback(app, event)
            global spout1pos;
            spout1pos.rest = str2double(app.sp1rest.Value);
        end

        % Callback function
        function sp2ext_Callback(app, event)
            global spout2pos;
            spout2pos.ext = str2double(app.sp2ext.Value);
        end

        % Callback function
        function sp2rest_Callback(app, event)
            global spout2pos;
            spout2pos.rest = str2double(app.sp2rest.Value);
        end

        % Callback function
        function spout1(app, event)
            global spout1pos;
            global s1 s2 current_servo_position;
            
            if contains(app.spout1_1.Text,'Out')
                s1 = spout1pos.ext;
                fprintf('Spout_C to ext at %4.3f\n',toc);
                app.spout1_1.Text = 'Spout C In';
            else
                s1 = spout1pos.rest;
                fprintf('Spout_C to rest at %4.3f\n',toc);
                app.spout1_1.Text = 'Spout C Out';
            end
            s2 = current_servo_position(2);
            control_spouts(s1,s2);
            current_servo_position = [s1,s2];
        end

        % Callback function
        function spout2(app, event)
            global spout2pos;
            global s1 s2 current_servo_position;
            
            if contains(app.spout2_1.Text,'Out')
                s2 = spout2pos.ext;
                fprintf('Spout_LR to ext at %4.3f\n',toc);
                app.spout2_1.Text = 'Spout LR In';
            else
                s2 = spout2pos.rest;
                fprintf('Spout_LR to rest at %4.3f\n',toc);
                app.spout2_1.Text = 'Spout LR Out';
            end
               s1 = current_servo_position(1);
               control_spouts(s1,s2);
               current_servo_position = [s1,s2];
        end

        % Callback function
        function stop_Callback(app, event)
            global run;
            
            run = 0;
        end

        % Callback function
        function test_Callback(app, event)
            
            % light_Callback(hObject, eventdata, handles,0,7);
            % pause(0.25);
            light_Callback(0,0);
            pause(0.25);
            light_Callback(0,7); % turn off
            light_Callback(0,1);
            pause(0.25);
            light_Callback(0,7); % turn off
            light_Callback(1,0);
            pause(0.25);
             light_Callback(1,7); % turn off
            light_Callback(1,1);
            pause(0.25);
            light_Callback(1,7); % turn off
            light_Callback(2,0);
            pause(0.25);
            light_Callback(2,7); % turn off
            light_Callback(2,1);
            pause(0.25);
            light_Callback(2,7);
        end

        % Callback function
        function tonel_Callback(app, event)
            global arduino;
            if strfind(app.tonel.Text,'ON') ~= 0
%                fprintf(arduino,'%c','=');
               tf = app.tonefreq.Value;
               btl = mod(tf,256); 
               bth = floor(tf/256);
%                fprintf(arduino,'%c',char(btl));
%                fprintf(arduino,'%c',char(bth));
%                fprintf(arduino,'%c','e');
%                fprintf(arduino,'%c',1);  % on
               app.tonel.Text = 'L OFF';
               
               fprintf(arduino,'%c',['=' btl (bth) 'e' 1]);
               
            else
%                fprintf(arduino,'%c','e');
               fprintf(arduino,'%c',['e' 0]);  % off
                app.tonel.Text = 'L ON';
            end
        end

        % Callback function
        function toner_Callback(app, event)
            global arduino;
            if strfind(app.toner.Text,'ON') ~= 0
%                fprintf(arduino,'%c','=');
               tf = app.tonefreq.Value;
               btl = mod(tf,256); 
               bth = floor(tf/256);
%                fprintf(arduino,'%c',char(btl));
%                fprintf(arduino,'%c',char(bth));
%                fprintf(arduino,'%c','f');
%                fprintf(arduino,'%c',1);  % on
               app.toner.Text = 'R OFF';
               fprintf(arduino,'%c',['=' char(btl) char(bth) 'f' 1]);
            else
%                 fprintf(arduino,'%c','f');
                fprintf(arduino,'%c',['f' 0]);  % off
                app.toner.Text = 'R ON';
            end
        end

        % Callback function
        function torest_Callback(app, event)
            global spout1pos spout2pos;
            global current_servo_position;
            % set bit spouts to rest position
            control_spouts(spout1pos.rest,spout2pos.rest);
            current_servo_position = [spout1pos.rest,spout2pos.rest];
        end

        % Callback function
        function retrial_mode_Callback(app, event)
            if ~app.retrial_mode.Value
                app.training_reward.Value = 0;
            end
        end

        % Callback function
        function training_reward_Callback(app, event)
            
            if app.training_reward.Value
                app.retrial_mode.Value = 1;
            end
        end

        % Callback function
        function vol_Callback(app, event)
            global arduino;
            global audio_volume;
            
%             fprintf(arduino,'%c','a');
            audio_volume = audio_volume + 1;
            audio_volume = min(audio_volume,63);
            fprintf(arduino,'%c',['a' audio_volume]);
            fprintf('audio volume = %d \n',audio_volume);
            
            
        end

        % Button pushed function: write
        function write_Callback(app, event)
            global notes notestime write_index;
            
            notes{write_index} = app.notes.Value;
            notestime{write_index} = toc;
            
            write_index = write_index + 1;
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            global run; 

            app.start.Text = 'Start';
            if run
                run = 0;
%                 fprintf(arduino,'%c','+');  
%                 fprintf(arduino,'%c',1); 
                return;
            end
                
            app.start.Text = 'Stop';        
            run = 1;            
            start_Callback(app);

        end

        % Button pushed function: valveL
        function valveLButtonPushed(app, event)
           
            global arduino;
%             fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',0);
            if contains(app.valveL.Text,'Open')
                fprintf(arduino,'%c',['k' 0 1]);
                app.valveL.Text = 'Close Valve L';
            else
                fprintf(arduino,'%c',['k' 0 0]);
                app.valveL.Text = 'Open Valve L';
            end
            
        end

        % Button pushed function: valveC
        function valveCButtonPushed(app, event)
            
            global arduino;
%             fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',1);
            if contains(app.valveC.Text,'Open')
                fprintf(arduino,'%c',['k' 1 1]);
                app.valveC.Text = 'Close Valve C';
            else
                fprintf(arduino,'%c',['k' 1 0]);
                app.valveC.Text = 'Open Valve C';
            end
            
        end

        % Button pushed function: valveR
        function valveRButtonPushed(app, event)
            
            global arduino;
%             fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',2);
            if contains(app.valveR.Text,'Open')
                fprintf(arduino,'%c',['k' 2 1]);
                app.valveR.Text = 'Close Valve R';
            else
                fprintf(arduino,'%c',['k' 2 0]);
                app.valveR.Text = 'Open Valve R';
            end
            
        end

        % Callback function
        function freqValueChanged(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['{' round(app.freq.Value)]);
            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            
              app.saveState('gui_settings.mat');
              delete(app);
              delete(instrfindall);
            
        end

        % Callback function
        function brightnessValueChanged(app, event)
            global arduino;
            
%             fprintf(arduino,'%c','}');
%             fprintf(arduino,'%c',round(app.brightness.Value));
%             fprintf(arduino,'%c',0); % do not initialize panels
            fprintf(arduino,'%c',['}' round(app.brightness.Value) 0]); % do not initialize panels
            
            
        end

        % Callback function
        function vol_2ButtonPushed(app, event)
            global arduino;
            global audio_volume;
            
%             fprintf(arduino,'%c','a');
            audio_volume = audio_volume - 1;
            audio_volume = max(audio_volume,0);
%             fprintf(arduino,'%c',audio_volume);
            fprintf(arduino,'%c',['a' audio_volume]);
            fprintf('audio volume = %d \n',audio_volume);
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            global run;
%             fprintf(arduino,'%c','+');  
%             fprintf(arduino,'%c',0);  
            run = 0;
        end

        % Callback function
        function DMValueChanged3(app, event)
            app.DM.Value = 1;
            app.SS.Value = 0;
            set_backgrounds(app);
            
        end

        % Callback function
        function SSValueChanged(app, event)
            app.SS.Value = 1;
            app.DM.Value = 0;
            set_backgrounds(app);
            
        end

        % Callback function
        function SideDiscriminationValueChanged(app, event)
            value = app.SideDiscrimination.Value;
            app.RateDiscrimination.Value = ~value;
            
        end

        % Callback function
        function Variable_SSDValueChanged(app, event)
            app.Variable_SSD.Value = 1;
            app.Variable_TCSA.Value = 0;
            app.Stable_Delays.Value = 0;
            
        end

        % Callback function
        function Variable_TCSAValueChanged(app, event)
            app.Variable_SSD.Value = 0;
            app.Variable_TCSA.Value = 1;
            app.Stable_Delays.Value = 0;
            
        end

        % Callback function
        function RateDiscriminationValueChanged(app, event)
            value = app.RateDiscrimination.Value;
            app.SideDiscrimination.Value = ~value;

        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            global settings_path;
            
            [fn,pn,FilterIndex]  = ...
                 uiputfile({'*.mat'},'Input data file',setttings_path);
            if (pn ~= 0)
                settings_path = pn;
            else
                 return;
            end
            app.saveState([pn filesep fn]);

        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            global settings_path;
            
            [fn,pn,FilterIndex]  = ...
                 uigetfile({'*.mat'},'Input data file',settings_path);
            if (pn ~= 0)
                settings_path = pn;
            else
                 return;
            end
            app.loadState([pn filesep fn]);
            
        end

        % Callback function
        function SmallPanelCheckBoxValueChanged(app, event)
            app.SmallPanelCheckBox.Value = 1;
            app.FullpanelCheckBox.Value = 0;
            app.BarsCheckBox.Value = 0;
        end

        % Callback function
        function FullpanelCheckBoxValueChanged(app, event)
            app.FullpanelCheckBox.Value = 1;
            app.SmallPanelCheckBox.Value = 0;
            app.BarsCheckBox.Value = 0;
        end

        % Callback function
        function BarsCheckBoxValueChanged(app, event)
            app.BarsCheckBox.Value = 1;
            app.SmallPanelCheckBox.Value = 0;
            app.FullpanelCheckBox.Value = 0;

        end

        % Callback function
        function Stable_DelaysValueChanged(app, event)
            app.Variable_SSD.Value = 0;
            app.Variable_TCSA.Value = 0;
            app.Stable_Delays.Value = 1;
            
        end

        % Callback function
        function two_rates_onlyValueChanged(app, event)
            app.two_rates_only.Value = 1;
            app.mixed_rates.Value = 0;

        end

        % Callback function
        function mixed_ratesValueChanged(app, event)
            app.mixed_rates.Value = 1;
            app.two_rates_only.Value = 0;
            
        end

        % Callback function
        function ButtonPushed(app, event)
            start_stimulus_with_delay(app);
        end

        % Callback function
        function DM_brightness_volumeValueChanged(app, event)
            value = app.DM_brightness_volume.Value;
            global arduino;
            v  = event.Value;
            fprintf(arduino,'%c',['}' v 1]);  % 

            
        end

        % Button pushed function: AllBackgroundButton
        function AllBackgroundButtonPushed(app, event)
            load('Y:\Ali O\yarm_rig\yarm\img test\imgwks.mat');
            
            %screen_a = 4;
            %screen_b = 2;
            %screen_c = 3;

            screen_a = 1;
            screen_b = 2;
            screen_c = 3;
            
            fullscreen_a(white_background,screen_a);
            fullscreen_b(white_background,screen_b);
            fullscreen_c(white_background,screen_c);

        end

        % Value changed function: EasyMode
        function EasyModeValueChanged(app, event)
            value = app.EasyMode.Value;
            if value
                app.ReverseMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                %app.PatternMatchMode.Value = 0;
                app.InitOnlyMode.Value = 0;
                app.AudioMode.Value = 0;
                app.rcMode.Value = 0;
            end

        end

        % Value changed function: FollowLightMode
        function FollowLightModeValueChanged(app, event)
            value = app.FollowLightMode.Value;
            if value
                app.ReverseMode.Value = 0;
                app.EasyMode.Value = 0;
               % app.PatternMatchMode.Value = 0;
                app.InitOnlyMode.Value = 0;
                app.AudioMode.Value = 0;
                app.rcMode.Value = 0;
            end            
        end

        % Button pushed function: closescreenButton
        function closescreenPushed(app, event)
            closescreen();
        end

        % Callback function
        function PatternModeValueChanged3(app, event)
%            value = app.PatternMatchMode.Value;
%            if value
%                app.EasyMode.Value = 0;
%                app.FollowLightMode.Value = 0;  
%                app.InitOnlyMode.Value = 0;
%                app.AudioMode.Value = 0;
%                app.rcMode.Value = 0;
%            end
        end

        % Callback function
        function pcontrast50ValueChanged(app, event)
%             value = app.pcontrast50.Value;
%             if value
%                 app.PatternMatchMode.Value = 0;
%                 app.EasyMode.Value = 0;
%                 app.FollowLightMode.Value = 0;  
%                 app.pcontrast75.Value = 0;  
%                 app.pcontrast80.Value = 0;
%                 app.pcontrast85.Value = 0; 
%                 app.pcontrast90.Value = 0; 
%                 app.pcontrast95.Value = 0; 
%             end
        end

        % Callback function
        function pcontrast75ValueChanged(app, event)
%             value = app.pcontrast75.Value;
%             if value
%                 app.PatternMatchMode.Value = 0;
%                 app.EasyMode.Value = 0;
%                 app.FollowLightMode.Value = 0;  
%                 app.pcontrast50.Value = 0;
%                 app.pcontrast80.Value = 0;
%                 app.pcontrast85.Value = 0; 
%                 app.pcontrast90.Value = 0; 
%                 app.pcontrast95.Value = 0; 
%             end
%             
        end

        % Callback function
        function pcontrast95ValueChanged(app, event)
%             value = app.pcontrast95.Value;
%             if value
%                 app.PatternMatchMode.Value = 0;
%                 app.EasyMode.Value = 0;
%                 app.FollowLightMode.Value = 0;  
%                 app.pcontrast50.Value = 0;
%                 app.pcontrast75.Value = 0;  
%                 app.pcontrast80.Value = 0;
%                 app.pcontrast85.Value = 0; 
%                 app.pcontrast90.Value = 0;        
%             end
        end

        % Callback function
        function pcontrast80ValueChanged(app, event)
%             value = app.pcontrast80.Value;
%             if value
%                 app.PatternMatchMode.Value = 0;
%                 app.EasyMode.Value = 0;
%                 app.FollowLightMode.Value = 0;  
%                 app.pcontrast50.Value = 0;
%                 app.pcontrast75.Value = 0;  
%                 app.pcontrast85.Value = 0; 
%                 app.pcontrast90.Value = 0; 
%                 app.pcontrast95.Value = 0; 
%             end
%             
        end

        % Callback function
        function pcontrast85ValueChanged(app, event)
%             value = app.pcontrast85.Value;
%             if value
%                 app.PatternMatchMode.Value = 0;
%                 app.EasyMode.Value = 0;
%                 app.FollowLightMode.Value = 0;  
%                 app.pcontrast50.Value = 0;
%                 app.pcontrast75.Value = 0;  
%                 app.pcontrast80.Value = 0;
%                 app.pcontrast90.Value = 0; 
%                 app.pcontrast95.Value = 0; 
%             end
%             
        end

        % Callback function
        function pcontrast90ValueChanged(app, event)
%             value = app.pcontrast90.Value;
%             if value
%                 app.PatternMatchMode.Value = 0;
%                 app.EasyMode.Value = 0;
%                 app.FollowLightMode.Value = 0;  
%                 app.pcontrast50.Value = 0;
%                 app.pcontrast75.Value = 0;  
%                 app.pcontrast80.Value = 0;
%                 app.pcontrast85.Value = 0; 
%                 app.pcontrast95.Value = 0; 
%             end
%             
        end

        % Value changed function: AudioMode
        function AudioModeValueChanged(app, event)
            value = app.AudioMode.Value;
            if value
                app.ReverseMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
             %   app.PatternMatchMode.Value = 0;
                app.InitOnlyMode.Value = 0;
            
            end
        end

        % Callback function
        function StarttimerButtonPushed(app, event)
%             valminutes=app.SessionTimeminEditField.Value;
%             valseconds=valminutes*60;
%             countdown=[valseconds:-1:0];
%             i=length(countdown)-1;
%             while i~=1
%                 app.TimeleftsEditField.Value = i;
%                 i=i-1;
%                 pause(1)
%                 if app.stopper==1
%                    app.TimeleftsEditField.Value=0;
%                    app.stopper=0;
%                     break
%                 end    
%             end 
        end

        % Callback function
        function StopandResettimerButtonPushed(app, event)
%             app.stopper=1;
            %valreset=app.TimeleftsEditField.Value;
            %valreset=0;
        end

        % Value changed function: rcMode
        function rcModeValueChanged(app, event)
            value = app.rcMode.Value;
            if value
                app.ReverseMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
            %    app.PatternMatchMode.Value = 0;
            end
%             if mod(state1_counter,app.rulechangecount.Value)
%                 app.PatternMatchMode.Value
%             end
            
        end

        % Value changed function: rulechangecount
        function rulechangecountValueChanged(app, event)
            value = app.rulechangecount.Value;
            
        end

        % Value changed function: InitOnlyMode
        function InitOnlyModeValueChanged(app, event)
            value = app.InitOnlyMode.Value;
            if value
                app.ReverseMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
          %      app.PatternMatchMode.Value = 0;
                app.AudioMode.Value = 0;
            end
            
        end

        % Value changed function: Mixstage
        function MixstageValueChanged(app, event)
            value = app.Mixstage.Value;
            if value
                app.ReverseMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
           %     app.PatternMatchMode.Value = 0;
            end
        end

        % Value changed function: ReverseMode
        function ReverseModeValueChanged(app, event)
            value = app.ReverseMode.Value;
            if value
                app.InitOnlyMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
          %      app.PatternMatchMode.Value = 0;
                app.AudioMode.Value = 0;
            end
        end

        % Value changed function: Eztest
        function EztestValueChanged(app, event)
            value = app.Eztest.Value;
            if value
                app.EasyMode.Value = 0;
                app.ReverseMode.Value = 0;
          %      app.InitOnlyMode.Value = 0;
          %      app.FollowLightMode.Value = 0;  
          %      app.PatternMatchMode.Value = 0;
                app.AudioMode.Value = 0;
            end
        end

        % Value changed function: NewStim
        function NewStimValueChanged(app, event)
            value = app.NewStim.Value;
            if value
                app.InitOnlyMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
          %      app.PatternMatchMode.Value = 0;
                app.AudioMode.Value = 0;
                app.Eztest.Value = 0;
                app.ReverseMode.Value = 0;
                app.MixStage.Value = 0;
            end
        end

        % Value changed function: fanmarbles
        function fanmarblesValueChanged(app, event)
            value = app.fanmarbles.Value;
            
        end

        % Value changed function: bypass
        function bypassValueChanged(app, event)
            value = app.bypass.Value;
            
        end

        % Value changed function: icecream_pizza
        function icecream_pizzaValueChanged(app, event)
            value = app.icecream_pizza.Value;
            
        end

        % Value changed function: nostim
        function nostimValueChanged(app, event)
            value = app.nostim.Value;
            %if value
            %    app.InitOnlyMode.Value = 0;
            %    app.EasyMode.Value = 0;
            %    app.FollowLightMode.Value = 0;  
            %    app.PatternMatchMode.Value = 0;
            %    app.AudioMode.Value = 0;
            %    app.Eztest.Value = 0;
            %    app.ReverseMode.Value = 0;
            %    app.MixStage.Value = 0;
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'Start';
            app.statenumber = uilabel(app.figure1);
            app.statenumber.Text = '0';
            app.animalid = uieditfield(app.figure1, 'text');
            app.animalid.Value = 'Y:\Ali O\yarm_rig\yarm\Mice Data\stage 3\er\';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Animal ID';
            app.dir = uibutton(app.figure1, 'push');
            app.dir.ButtonPushedFcn = createCallbackFcn(app, @dir_Callback, true);
            app.dir.Text = 'Dir';
            app.notes = uieditfield(app.figure1, 'text');
            app.notes.Value = 'test';
            app.text26 = uilabel(app.figure1);
            app.text26.Text = 'Notes';
            app.state1_count = uilabel(app.figure1);
            app.state1_count.Text = '0';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = 'State';
            app.text36 = uilabel(app.figure1);
            app.text36.Text = 'Trial #';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Valve Control';
            app.rewardL = uibutton(app.uipanel2, 'push');
            app.rewardL.ButtonPushedFcn = createCallbackFcn(app, @rewardL_Callback, true);
            app.rewardL.Text = 'Reward 1';
            app.valveR = uibutton(app.uipanel2, 'push');
            app.valveR.ButtonPushedFcn = createCallbackFcn(app, @valveRButtonPushed, true);
            app.valveR.Text = 'Open Valve R';
            app.valveL = uibutton(app.uipanel2, 'push');
            app.valveL.ButtonPushedFcn = createCallbackFcn(app, @valveLButtonPushed, true);
            app.valveL.Text = 'Open Valve L';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Text = 'reward_pulses (ms)';
            app.r_pulse_L = uieditfield(app.uipanel2, 'text');
            app.r_pulse_L.ValueChangedFcn = createCallbackFcn(app, @r_pulse_L_Callback, true);
            app.r_pulse_L.Value = '35';
            app.rewardR = uibutton(app.uipanel2, 'push');
            app.rewardR.ButtonPushedFcn = createCallbackFcn(app, @rewardR_Callback, true);
            app.rewardR.Text = 'Reward 2';
            app.rewardC = uibutton(app.uipanel2, 'push');
            app.rewardC.ButtonPushedFcn = createCallbackFcn(app, @rewardC_Callback, true);
            app.rewardC.Text = 'Reward 3';
            app.valveC = uibutton(app.uipanel2, 'push');
            app.valveC.ButtonPushedFcn = createCallbackFcn(app, @valveCButtonPushed, true);
            app.valveC.Text = 'Open Valve C';
            app.r_pulse_R = uieditfield(app.uipanel2, 'text');
            app.r_pulse_R.ValueChangedFcn = createCallbackFcn(app, @r_pulse_R_Callback, true);
            app.r_pulse_R.Value = '40';
            app.r_pulse_C = uieditfield(app.uipanel2, 'text');
            app.r_pulse_C.ValueChangedFcn = createCallbackFcn(app, @r_pulse_C_Callback, true);
            app.r_pulse_C.Value = '45';
            app.write = uibutton(app.figure1, 'push');
            app.write.ButtonPushedFcn = createCallbackFcn(app, @write_Callback, true);
            app.write.Text = 'Write Note';
            app.Panel_General = uipanel(app.figure1);
            app.ExperimentDurationsLabel = uilabel(app.Panel_General);
            app.ExperimentDurationsLabel.Text = 'Experiment Duration (s)';
            app.ex_duration = uieditfield(app.Panel_General, 'numeric');
            app.ex_duration.Value = 3600;
            app.NumberofLicksRequiredLabel = uilabel(app.Panel_General);
            app.NumberofLicksRequiredLabel.Text = 'Number of Licks Required';
            app.lick_count_requirement = uieditfield(app.Panel_General, 'numeric');
            app.lick_count_requirement.Value = 1;
            app.LickTimeRequirementLabel = uilabel(app.Panel_General);
            app.LickTimeRequirementLabel.Text = 'Lick Time Requirement';
            app.lick_time_requirement = uieditfield(app.Panel_General, 'numeric');
            app.lick_time_requirement.Value = 1;
            app.text19_2 = uilabel(app.Panel_General);
            app.text19_2.Text = 'General Controls';
            app.PercentStimLLabel = uilabel(app.Panel_General);
            app.PercentStimLLabel.Text = 'Percent StimL';
            app.percent_left = uieditfield(app.Panel_General, 'numeric');
            app.percent_left.Value = 50;
            app.text29_7 = uilabel(app.Panel_General);
            app.text29_7.Text = 'Right correct';
            app.text29_4 = uilabel(app.Panel_General);
            app.text29_4.Text = 'Left correct';
            app.LoadSettingsButton = uibutton(app.figure1, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.figure1, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.AllBackgroundButton = uibutton(app.figure1, 'push');
            app.AllBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @AllBackgroundButtonPushed, true);
            app.AllBackgroundButton.Text = 'AllBackground';
            app.EasyMode = uicheckbox(app.figure1);
            app.EasyMode.ValueChangedFcn = createCallbackFcn(app, @EasyModeValueChanged, true);
            app.EasyMode.Text = 'EasyMode (stage 1)';
            app.FollowLightMode = uicheckbox(app.figure1);
            app.FollowLightMode.ValueChangedFcn = createCallbackFcn(app, @FollowLightModeValueChanged, true);
            app.FollowLightMode.Text = 'FollowLightMode (stage 2)';
            app.TimeoutPunishmentsLabel = uilabel(app.figure1);
            app.TimeoutPunishmentsLabel.Text = 'Timeout Punishment (s)';
            app.timeout_punishment = uieditfield(app.figure1, 'numeric');
            app.timeout_punishment.Value = 10;
            app.TimeoutPunishmentCheckBox = uicheckbox(app.figure1);
            app.TimeoutPunishmentCheckBox.Text = 'Timeout Punishment';
            app.TimeoutPunishmentCheckBox.Value = true;
            app.closescreenButton = uibutton(app.figure1, 'push');
            app.closescreenButton.ButtonPushedFcn = createCallbackFcn(app, @closescreenPushed, true);
            app.closescreenButton.Text = 'closescreen';
            app.AudioMode = uicheckbox(app.figure1);
            app.AudioMode.ValueChangedFcn = createCallbackFcn(app, @AudioModeValueChanged, true);
            app.AudioMode.Text = 'AudioMode ';
            app.rcMode = uicheckbox(app.figure1);
            app.rcMode.ValueChangedFcn = createCallbackFcn(app, @rcModeValueChanged, true);
            app.rcMode.Text = 'RuleChangeMode (stage 5)';
            app.RuleChangeCountLabel = uilabel(app.figure1);
            app.RuleChangeCountLabel.Text = 'Rule Change Count';
            app.rulechangecount = uieditfield(app.figure1, 'numeric');
            app.rulechangecount.ValueChangedFcn = createCallbackFcn(app, @rulechangecountValueChanged, true);
            app.rulechangecount.Value = 5;
            app.InitOnlyMode = uicheckbox(app.figure1);
            app.InitOnlyMode.ValueChangedFcn = createCallbackFcn(app, @InitOnlyModeValueChanged, true);
            app.InitOnlyMode.Text = 'InitOnlyMode (stage 100)';
            app.PercentStage2Label = uilabel(app.figure1);
            app.PercentStage2Label.Text = 'Percent Stage2';
            app.percent_stage2 = uieditfield(app.figure1, 'numeric');
            app.percent_stage2.Value = 50;
            app.Mixstage = uicheckbox(app.figure1);
            app.Mixstage.ValueChangedFcn = createCallbackFcn(app, @MixstageValueChanged, true);
            app.Mixstage.Text = 'Mixstage (stage 3)';
            app.ReverseMode = uicheckbox(app.figure1);
            app.ReverseMode.ValueChangedFcn = createCallbackFcn(app, @ReverseModeValueChanged, true);
            app.ReverseMode.Text = 'ReverseMode (stage 200)';
            app.Eztest = uicheckbox(app.figure1);
            app.Eztest.ValueChangedFcn = createCallbackFcn(app, @EztestValueChanged, true);
            app.Eztest.Text = 'Eztest (stage 101)';
            app.NewStim = uicheckbox(app.figure1);
            app.NewStim.ValueChangedFcn = createCallbackFcn(app, @NewStimValueChanged, true);
            app.NewStim.Text = 'NewStim (stage 102)';
            app.fanmarbles = uicheckbox(app.figure1);
            app.fanmarbles.ValueChangedFcn = createCallbackFcn(app, @fanmarblesValueChanged, true);
            app.fanmarbles.Text = 'FAN / MARBLES';
            app.bypass = uicheckbox(app.figure1);
            app.bypass.ValueChangedFcn = createCallbackFcn(app, @bypassValueChanged, true);
            app.bypass.Text = 'bypass';
            app.icecream_pizza = uicheckbox(app.figure1);
            app.icecream_pizza.ValueChangedFcn = createCallbackFcn(app, @icecream_pizzaValueChanged, true);
            app.icecream_pizza.Text = 'icecream/pizza';
            app.reward_counter = uilabel(app.figure1);
            app.reward_counter.Text = '0';
            app.correcttrialslimitLabel = uilabel(app.figure1);
            app.correcttrialslimitLabel.Text = 'correct trials limit';
            app.corr_trials_lim = uieditfield(app.figure1, 'numeric');
            app.corr_trials_lim.Value = 10;
            app.text29_8 = uilabel(app.figure1);
            app.text29_8.Text = 'Reward_count';
            app.PatternMatchMode = uicheckbox(app.figure1);
            app.PatternMatchMode.Text = 'PatternMatch (stage 4)';
            app.nostim = uicheckbox(app.figure1);
            app.nostim.ValueChangedFcn = createCallbackFcn(app, @nostimValueChanged, true);
            app.nostim.Text = 'NO STIM';
            app.nostim.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Decision_Making_Rig2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end