classdef Decision_MakingB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        start                         matlab.ui.control.Button
        statenumber                   matlab.ui.control.Label
        animalid                      matlab.ui.control.EditField
        text19                        matlab.ui.control.Label
        dir                           matlab.ui.control.Button
        notes                         matlab.ui.control.EditField
        text26                        matlab.ui.control.Label
        state1_count                  matlab.ui.control.Label
        text29                        matlab.ui.control.Label
        text36                        matlab.ui.control.Label
        uipanel2                      matlab.ui.container.Panel
        rewardL                       matlab.ui.control.Button
        valveR                        matlab.ui.control.Button
        valveL                        matlab.ui.control.Button
        text4                         matlab.ui.control.Label
        reward_pulse_C                matlab.ui.control.EditField
        reward_pulse_R                matlab.ui.control.EditField
        reward_pulse_L                matlab.ui.control.EditField
        rewardR                       matlab.ui.control.Button
        rewardC                       matlab.ui.control.Button
        valveC                        matlab.ui.control.Button
        write                         matlab.ui.control.Button
        Panel_General                 matlab.ui.container.Panel
        ExperimentDurationsLabel      matlab.ui.control.Label
        ex_duration                   matlab.ui.control.NumericEditField
        State3TimeoutsLabel           matlab.ui.control.Label
        state3_timeout                matlab.ui.control.NumericEditField
        NumberofLicksRequiredLabel    matlab.ui.control.Label
        lick_count_requirement        matlab.ui.control.NumericEditField
        LickTimeRequirementLabel      matlab.ui.control.Label
        lick_time_requirement         matlab.ui.control.NumericEditField
        text19_2                      matlab.ui.control.Label
        PercentStimLLabel             matlab.ui.control.Label
        percent_stim                  matlab.ui.control.NumericEditField
        StarttimerButton              matlab.ui.control.Button
        TimeleftsEditFieldLabel       matlab.ui.control.Label
        TimeleftsEditField            matlab.ui.control.NumericEditField
        SessionTimeminEditFieldLabel  matlab.ui.control.Label
        SessionTimeminEditField       matlab.ui.control.NumericEditField
        StopandResettimerButton       matlab.ui.control.Button
        LoadSettingsButton            matlab.ui.control.Button
        SaveSettingsButton            matlab.ui.control.Button
        text29_4                      matlab.ui.control.Label
        text29_7                      matlab.ui.control.Label
        uipanel3                      matlab.ui.container.Panel
        toner                         matlab.ui.control.Button
        tonel                         matlab.ui.control.Button
        vol                           matlab.ui.control.Button
        vol_2                         matlab.ui.control.Button
        AllBackgroundButton           matlab.ui.control.Button
        EasyMode                      matlab.ui.control.CheckBox
        FollowLightMode               matlab.ui.control.CheckBox
        TimeoutPunishmentsLabel       matlab.ui.control.Label
        timeout_punishment            matlab.ui.control.NumericEditField
        TimeoutPunishmentCheckBox     matlab.ui.control.CheckBox
        LeftSpatialFreqWidthLabel     matlab.ui.control.Label
        sfg_L                         matlab.ui.control.NumericEditField
        RightSpatialFreqWidthLabel    matlab.ui.control.Label
        sfg_R                         matlab.ui.control.NumericEditField
        closescreenButton             matlab.ui.control.Button
        PatternMatchMode              matlab.ui.control.CheckBox
        pcontrast50                   matlab.ui.control.CheckBox
        pcontrast75                   matlab.ui.control.CheckBox
        pcontrast95                   matlab.ui.control.CheckBox
        pcontrast80                   matlab.ui.control.CheckBox
        pcontrast85                   matlab.ui.control.CheckBox
        pcontrast90                   matlab.ui.control.CheckBox
        AudioMode                     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        stopper % stop timer button
    end
    
    methods (Access = private)


        function saveState(app,fn)
            
            h = findobj(app.figure1,'Type','uicheckbox');
            xarr = zeros(length(h),1);
            for i = 1:length(h)
               xarr(i) = h(i).Value;
            end
            state.checkboxes = xarr;
            
            
             h = findobj(app.figure1,'Type','uieditfield');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.editfields = xa;
             
             h = findobj(app.figure1,'Type','uinumericeditfield');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.uinumericeditfields = xa;
             
             h = findobj(app.figure1,'Type','uiradiobutton');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.uiradiobuttons = xa;
             
             h = findobj(app.figure1,'Type','uiDropDown');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.dropdowns = xa;
             
           ps = app.figure1.Position;
           save(fn,'state','ps');
        end

        
        function loadState(app,fn)
%                return
%             load('MyAppDefaultValues.mat','state');
            load(fn);
            
            h = findobj(app.figure1,'Type','uicheckbox');
            for i = 1:length(h)
               h(i).Value = state.checkboxes(i);
            end
            
             h = findobj(app.figure1,'Type','uieditfield');
             for i = 1:length(h)
                h(i).Value = state.editfields{i};
             end
             
             h = findobj(app.figure1,'Type','uinumericeditfield');
             for i = 1:length(h)
                h(i).Value = state.uinumericeditfields{i};
             end
             
             
             h = findobj(app.figure1,'Type','uiradiobutton');
              for i = 1:length(h)
                 h(i).Value = state.uiradiobuttons{i};
              end
              
             h = findobj(app.figure1,'Type','uiDropDown');
              for i = 1:length(h)
                 h(i).Value = state.dropdowns{i};
              end
              
             app.figure1.Position = ps;

        end        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app)
            
            
              addpath(genpath(['.' filesep 'general' filesep]));
              addpath(genpath(['.' filesep 'DM' filesep]));
              addpath(genpath(['.' filesep 'SS' filesep]));

              app.loadState('gui_settings.mat');

%               app.figure1.Position = [800 500 1216 755];
              app.figure1.Name = 'yarm';
%               app.start.Enable = 'off';
              app.start.Visible = 'off';
              init(app);
              app.start.Visible = 'on';
              app.start.Enable = 'on';

        end

        % Callback function
        function airpuff_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c','d');  %r
            fprintf('%4.3f air_puff \n',toc);
        end

        % Callback function
        function airpuff_duration_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['u' round(app.airpuff_duration.Value)]);
        end

        % Callback function
        function cclock_Callback(app, event)
            global arduino;
            
            if contains(app.cclock.Text,'Start')
                fprintf(arduino,'%c','(');
                app.cclock.Text = 'Stop Camera Clock';
            else
                fprintf(arduino,'%c',')');
                app.cclock.Text = 'Start Camera Clock';
            end
        end

        % Button pushed function: dir
        function dir_Callback(app, event)
            global dirpath;
            if isempty(dirpath)
                dirpath = pwd;
            end
            ddir = uigetdir(dirpath);
            
            app.animalid.Value = [ddir '\'];
        end

        % Callback function
        function flashc_Callback(app, event)
            if app.allleds.Value
                light_Callback(1,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(1,7);
            else
            light_Callback(1,0);
            pause(0.25);
            light_Callback(1,7);  % turn off
            light_Callback(1,1);
            pause(0.25);
            light_Callback(1,7);  % turn off
            end
        end

        % Callback function
        function flashl_Callback(app, event)
            if  app.allleds.Value
                light_Callback(0,5);
                light_Callback(0,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(0,7);
            elseif app.light2.Value
                light_Callback(0,5);
                pause(0.25);
                light_Callback(0,7);
            else
                light_Callback(0,5);
                pause(0.25);
                light_Callback(0,7);  % turn off
                light_Callback(0,0);
                pause(0.25);
                light_Callback(0,7);  % turn off
                light_Callback(0,1);
                pause(0.25);
                light_Callback(0,7);  % turn off
            end
        end

        % Callback function
        function flashr_Callback(app, event)
            if  app.allleds.Value
                light_Callback(2,5);
                light_Callback(2,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(2,7);
            elseif  app.light2.Value
                light_Callback(2,5);
                pause(0.25);
                light_Callback(2,7); % turn off
            else
                light_Callback(2,5);
                pause(0.25);
                light_Callback(2,7); % turn off
                light_Callback(2,1);
                pause(0.25);
                light_Callback(2,7); % turn off
                light_Callback(2,0);
                pause(0.25);
                light_Callback(2,7);  % turn off
            end
        end

        % Callback function
        function fullout_Callback(app, event)
            global spout1pos spout2pos;
            global current_servo_position;
            
            control_spouts(spout1pos.ext,spout2pos.ext);
            current_servo_position =  [spout1pos.ext,spout2pos.ext];
        end

        % Callback function
        function fullretract_Callback(app, event)
            
            % fully retract both spouts
            global current_servo_position;
            
            control_spouts(0,0);
            current_servo_position = [0,0];
        end

        % Callback function
        function light2_Callback(app, event)
            if app.light2.Value
               app.light2.Value = 1;
               app.light8.Value = 0;
            else
               app.light2.Value = 0;
               app.light8.Value = 1;
            end
        end

        % Callback function
        function light8_Callback(app, event)
            if app.light8.Value
               app.light2.Value = 0;
               app.light8.Value = 1;
            else
               app.light2.Value = 1;
               app.light8.Value = 0;
            end
        end

        % Callback function
        function per_R_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['w' round(app.reward_pulse_R.Value)]);
        end

        % Button pushed function: rewardC
        function rewardC_Callback(app, event)
            
            reward_Callback(app,'C');
        end

        % Button pushed function: rewardL
        function rewardL_Callback(app, event)
            
            reward_Callback(app,'L');
        end

        % Button pushed function: rewardR
        function rewardR_Callback(app, event)
            reward_Callback(app,'R');

        end

        % Value changed function: reward_pulse_C
        function reward_pulse_C_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['w' round(str2double(app.reward_pulse_C.Value))]);
        end

        % Value changed function: reward_pulse_L
        function reward_pulse_L_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['v' round(str2double(app.reward_pulse_L.Value))]);
        end

        % Value changed function: reward_pulse_R
        function reward_pulse_R_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['q' round(str2double(app.reward_pulse_R.Value))]);
        end

        % Callback function
        function sp1ext_Callback(app, event)
            global spout1pos;
            spout1pos.ext = str2double(app.sp1ext.Value);
        end

        % Callback function
        function sp1rest_Callback(app, event)
            global spout1pos;
            spout1pos.rest = str2double(app.sp1rest.Value);
        end

        % Callback function
        function sp2ext_Callback(app, event)
            global spout2pos;
            spout2pos.ext = str2double(app.sp2ext.Value);
        end

        % Callback function
        function sp2rest_Callback(app, event)
            global spout2pos;
            spout2pos.rest = str2double(app.sp2rest.Value);
        end

        % Callback function
        function spout1(app, event)
            global spout1pos;
            global s1 s2 current_servo_position;
            
            if contains(app.spout1_1.Text,'Out')
                s1 = spout1pos.ext;
                fprintf('Spout_C to ext at %4.3f\n',toc);
                app.spout1_1.Text = 'Spout C In';
            else
                s1 = spout1pos.rest;
                fprintf('Spout_C to rest at %4.3f\n',toc);
                app.spout1_1.Text = 'Spout C Out';
            end
            s2 = current_servo_position(2);
            control_spouts(s1,s2);
            current_servo_position = [s1,s2];
        end

        % Callback function
        function spout2(app, event)
            global spout2pos;
            global s1 s2 current_servo_position;
            
            if contains(app.spout2_1.Text,'Out')
                s2 = spout2pos.ext;
                fprintf('Spout_LR to ext at %4.3f\n',toc);
                app.spout2_1.Text = 'Spout LR In';
            else
                s2 = spout2pos.rest;
                fprintf('Spout_LR to rest at %4.3f\n',toc);
                app.spout2_1.Text = 'Spout LR Out';
            end
               s1 = current_servo_position(1);
               control_spouts(s1,s2);
               current_servo_position = [s1,s2];
        end

        % Callback function
        function stop_Callback(app, event)
            global run;
            
            run = 0;
        end

        % Callback function
        function test_Callback(app, event)
            
            % light_Callback(hObject, eventdata, handles,0,7);
            % pause(0.25);
            light_Callback(0,0);
            pause(0.25);
            light_Callback(0,7); % turn off
            light_Callback(0,1);
            pause(0.25);
            light_Callback(0,7); % turn off
            light_Callback(1,0);
            pause(0.25);
             light_Callback(1,7); % turn off
            light_Callback(1,1);
            pause(0.25);
            light_Callback(1,7); % turn off
            light_Callback(2,0);
            pause(0.25);
            light_Callback(2,7); % turn off
            light_Callback(2,1);
            pause(0.25);
            light_Callback(2,7);
        end

        % Button pushed function: tonel
        function tonel_Callback(app, event)
            global arduino;
            if strfind(app.tonel.Text,'ON') ~= 0
%                fprintf(arduino,'%c','=');
               tf = app.tonefreq.Value;
               btl = mod(tf,256); 
               bth = floor(tf/256);
%                fprintf(arduino,'%c',char(btl));
%                fprintf(arduino,'%c',char(bth));
%                fprintf(arduino,'%c','e');
%                fprintf(arduino,'%c',1);  % on
               app.tonel.Text = 'L OFF';
               
               fprintf(arduino,'%c',['=' btl (bth) 'e' 1]);
               
            else
%                fprintf(arduino,'%c','e');
               fprintf(arduino,'%c',['e' 0]);  % off
                app.tonel.Text = 'L ON';
            end
        end

        % Button pushed function: toner
        function toner_Callback(app, event)
            global arduino;
            if strfind(app.toner.Text,'ON') ~= 0
%                fprintf(arduino,'%c','=');
               tf = app.tonefreq.Value;
               btl = mod(tf,256); 
               bth = floor(tf/256);
%                fprintf(arduino,'%c',char(btl));
%                fprintf(arduino,'%c',char(bth));
%                fprintf(arduino,'%c','f');
%                fprintf(arduino,'%c',1);  % on
               app.toner.Text = 'R OFF';
               fprintf(arduino,'%c',['=' char(btl) char(bth) 'f' 1]);
            else
%                 fprintf(arduino,'%c','f');
                fprintf(arduino,'%c',['f' 0]);  % off
                app.toner.Text = 'R ON';
            end
        end

        % Callback function
        function torest_Callback(app, event)
            global spout1pos spout2pos;
            global current_servo_position;
            % set bit spouts to rest position
            control_spouts(spout1pos.rest,spout2pos.rest);
            current_servo_position = [spout1pos.rest,spout2pos.rest];
        end

        % Callback function
        function retrial_mode_Callback(app, event)
            if ~app.retrial_mode.Value
                app.training_reward.Value = 0;
            end
        end

        % Callback function
        function training_reward_Callback(app, event)
            
            if app.training_reward.Value
                app.retrial_mode.Value = 1;
            end
        end

        % Button pushed function: vol
        function vol_Callback(app, event)
            global arduino;
            global audio_volume;
            
%             fprintf(arduino,'%c','a');
            audio_volume = audio_volume + 1;
            audio_volume = min(audio_volume,63);
            fprintf(arduino,'%c',['a' audio_volume]);
            fprintf('audio volume = %d \n',audio_volume);
            
            
        end

        % Button pushed function: write
        function write_Callback(app, event)
            global notes notestime write_index;
            
            notes{write_index} = app.notes.Value;
            notestime{write_index} = toc;
            
            write_index = write_index + 1;
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            global run; 

            app.start.Text = 'Start';
            if run
                run = 0;
                return;
            end
                
            app.start.Text = 'Stop';        
            run = 1;                      
            start_Callback(app);

        end

        % Button pushed function: valveL
        function valveLButtonPushed(app, event)
           
            global arduino;
%             fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',0);
            if contains(app.valveL.Text,'Open')
                fprintf(arduino,'%c',['k' 0 1]);
                app.valveL.Text = 'Close Valve L';
            else
                fprintf(arduino,'%c',['k' 0 0]);
                app.valveL.Text = 'Open Valve L';
            end
            
        end

        % Button pushed function: valveC
        function valveCButtonPushed(app, event)
            
            global arduino;
%             fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',1);
            if contains(app.valveC.Text,'Open')
                fprintf(arduino,'%c',['k' 1 1]);
                app.valveC.Text = 'Close Valve C';
            else
                fprintf(arduino,'%c',['k' 1 0]);
                app.valveC.Text = 'Open Valve C';
            end
            
        end

        % Button pushed function: valveR
        function valveRButtonPushed(app, event)
            
            global arduino;
%             fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',2);
            if contains(app.valveR.Text,'Open')
                fprintf(arduino,'%c',['k' 2 1]);
                app.valveR.Text = 'Close Valve R';
            else
                fprintf(arduino,'%c',['k' 2 0]);
                app.valveR.Text = 'Open Valve R';
            end
            
        end

        % Callback function
        function freqValueChanged(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['{' round(app.freq.Value)]);
            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            
              app.saveState('gui_settings.mat');
              delete(app);
              delete(instrfindall);
            
        end

        % Callback function
        function brightnessValueChanged(app, event)
            global arduino;
            
%             fprintf(arduino,'%c','}');
%             fprintf(arduino,'%c',round(app.brightness.Value));
%             fprintf(arduino,'%c',0); % do not initialize panels
            fprintf(arduino,'%c',['}' round(app.brightness.Value) 0]); % do not initialize panels
            
            
        end

        % Button pushed function: vol_2
        function vol_2ButtonPushed(app, event)
            global arduino;
            global audio_volume;
            
%             fprintf(arduino,'%c','a');
            audio_volume = audio_volume - 1;
            audio_volume = max(audio_volume,0);
%             fprintf(arduino,'%c',audio_volume);
            fprintf(arduino,'%c',['a' audio_volume]);
            fprintf('audio volume = %d \n',audio_volume);
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            global run;
            run = 0;
        end

        % Callback function
        function DMValueChanged3(app, event)
            app.DM.Value = 1;
            app.SS.Value = 0;
            set_backgrounds(app);
            
        end

        % Callback function
        function SSValueChanged(app, event)
            app.SS.Value = 1;
            app.DM.Value = 0;
            set_backgrounds(app);
            
        end

        % Callback function
        function SideDiscriminationValueChanged(app, event)
            value = app.SideDiscrimination.Value;
            app.RateDiscrimination.Value = ~value;
            
        end

        % Callback function
        function Variable_SSDValueChanged(app, event)
            app.Variable_SSD.Value = 1;
            app.Variable_TCSA.Value = 0;
            app.Stable_Delays.Value = 0;
            
        end

        % Callback function
        function Variable_TCSAValueChanged(app, event)
            app.Variable_SSD.Value = 0;
            app.Variable_TCSA.Value = 1;
            app.Stable_Delays.Value = 0;
            
        end

        % Callback function
        function RateDiscriminationValueChanged(app, event)
            value = app.RateDiscrimination.Value;
            app.SideDiscrimination.Value = ~value;

        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            global settings_path;
            
            [fn,pn,FilterIndex]  = ...
                 uiputfile({'*.mat'},'Input data file',setttings_path);
            if (pn ~= 0)
                settings_path = pn;
            else
                 return;
            end
            app.saveState([pn filesep fn]);

        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            global settings_path;
            
            [fn,pn,FilterIndex]  = ...
                 uigetfile({'*.mat'},'Input data file',settings_path);
            if (pn ~= 0)
                settings_path = pn;
            else
                 return;
            end
            app.loadState([pn filesep fn]);
            
        end

        % Callback function
        function SmallPanelCheckBoxValueChanged(app, event)
            app.SmallPanelCheckBox.Value = 1;
            app.FullpanelCheckBox.Value = 0;
            app.BarsCheckBox.Value = 0;
        end

        % Callback function
        function FullpanelCheckBoxValueChanged(app, event)
            app.FullpanelCheckBox.Value = 1;
            app.SmallPanelCheckBox.Value = 0;
            app.BarsCheckBox.Value = 0;
        end

        % Callback function
        function BarsCheckBoxValueChanged(app, event)
            app.BarsCheckBox.Value = 1;
            app.SmallPanelCheckBox.Value = 0;
            app.FullpanelCheckBox.Value = 0;

        end

        % Callback function
        function Stable_DelaysValueChanged(app, event)
            app.Variable_SSD.Value = 0;
            app.Variable_TCSA.Value = 0;
            app.Stable_Delays.Value = 1;
            
        end

        % Callback function
        function two_rates_onlyValueChanged(app, event)
            app.two_rates_only.Value = 1;
            app.mixed_rates.Value = 0;

        end

        % Callback function
        function mixed_ratesValueChanged(app, event)
            app.mixed_rates.Value = 1;
            app.two_rates_only.Value = 0;
            
        end

        % Callback function
        function ButtonPushed(app, event)
            start_stimulus_with_delay(app);
        end

        % Callback function
        function DM_brightness_volumeValueChanged(app, event)
            value = app.DM_brightness_volume.Value;
            global arduino;
            v  = event.Value;
            fprintf(arduino,'%c',['}' v 1]);  % 

            
        end

        % Button pushed function: AllBackgroundButton
        function AllBackgroundButtonPushed(app, event)
            load('Y:\Ali O\yarm_rig\yarm\img test\imgwks.mat');
            fullscreen2(white_background,2);
            fullscreen3(white_background,3);
            fullscreen4(white_background,4);

        end

        % Value changed function: EasyMode
        function EasyModeValueChanged(app, event)
            value = app.EasyMode.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast50.Value = 0;
                app.pcontrast75.Value = 0;  
                app.pcontrast80.Value = 0;
                app.pcontrast85.Value = 0; 
                app.pcontrast90.Value = 0; 
                app.pcontrast95.Value = 0; 
            end

        end

        % Value changed function: FollowLightMode
        function FollowLightModeValueChanged(app, event)
            value = app.FollowLightMode.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.EasyMode.Value = 0; 
                app.pcontrast50.Value = 0;
                app.pcontrast75.Value = 0;  
                app.pcontrast80.Value = 0;
                app.pcontrast85.Value = 0; 
                app.pcontrast90.Value = 0; 
                app.pcontrast95.Value = 0; 
            end            
        end

        % Button pushed function: closescreenButton
        function closescreenPushed(app, event)
            closescreen();
        end

        % Value changed function: PatternMatchMode
        function PatternModeValueChanged3(app, event)
            value = app.PatternMatchMode.Value;
            if value
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast50.Value = 0;
                app.pcontrast75.Value = 0;  
                app.pcontrast80.Value = 0;
                app.pcontrast85.Value = 0; 
                app.pcontrast90.Value = 0; 
                app.pcontrast95.Value = 0; 
                app.AudioMode.Value = 0;
            end
        end

        % Value changed function: pcontrast50
        function pcontrast50ValueChanged(app, event)
            value = app.pcontrast50.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast75.Value = 0;  
                app.pcontrast80.Value = 0;
                app.pcontrast85.Value = 0; 
                app.pcontrast90.Value = 0; 
                app.pcontrast95.Value = 0; 
            end
        end

        % Value changed function: pcontrast75
        function pcontrast75ValueChanged(app, event)
            value = app.pcontrast75.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast50.Value = 0;
                app.pcontrast80.Value = 0;
                app.pcontrast85.Value = 0; 
                app.pcontrast90.Value = 0; 
                app.pcontrast95.Value = 0; 
            end
            
        end

        % Value changed function: pcontrast95
        function pcontrast95ValueChanged(app, event)
            value = app.pcontrast95.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast50.Value = 0;
                app.pcontrast75.Value = 0;  
                app.pcontrast80.Value = 0;
                app.pcontrast85.Value = 0; 
                app.pcontrast90.Value = 0;        
            end
        end

        % Value changed function: pcontrast80
        function pcontrast80ValueChanged(app, event)
            value = app.pcontrast80.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast50.Value = 0;
                app.pcontrast75.Value = 0;  
                app.pcontrast85.Value = 0; 
                app.pcontrast90.Value = 0; 
                app.pcontrast95.Value = 0; 
            end
            
        end

        % Value changed function: pcontrast85
        function pcontrast85ValueChanged(app, event)
            value = app.pcontrast85.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast50.Value = 0;
                app.pcontrast75.Value = 0;  
                app.pcontrast80.Value = 0;
                app.pcontrast90.Value = 0; 
                app.pcontrast95.Value = 0; 
            end
            
        end

        % Value changed function: pcontrast90
        function pcontrast90ValueChanged(app, event)
            value = app.pcontrast90.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast50.Value = 0;
                app.pcontrast75.Value = 0;  
                app.pcontrast80.Value = 0;
                app.pcontrast85.Value = 0; 
                app.pcontrast95.Value = 0; 
            end
            
        end

        % Value changed function: AudioMode
        function AudioModeValueChanged(app, event)
            value = app.AudioMode.Value;
            if value
                app.PatternMatchMode.Value = 0;
                app.EasyMode.Value = 0;
                app.FollowLightMode.Value = 0;  
                app.pcontrast50.Value = 0;
                app.pcontrast75.Value = 0;  
                app.pcontrast80.Value = 0;
                app.pcontrast85.Value = 0; 
                app.pcontrast90.Value = 0;
                app.pcontrast95.Value = 0; 
            
            end
        end

        % Button pushed function: StarttimerButton
        function StarttimerButtonPushed(app, event)
            valminutes=app.SessionTimeminEditField.Value;
            valseconds=valminutes*60;
            countdown=[valseconds:-1:0];
            i=length(countdown)-1;
            while i~=1
                app.TimeleftsEditField.Value = i;
                i=i-1;
                pause(1)
                if app.stopper==1
                   app.TimeleftsEditField.Value=0;
                   app.stopper=0;
                    break
                end    
            end 
        end

        % Button pushed function: StopandResettimerButton
        function StopandResettimerButtonPushed(app, event)
            app.stopper = 1;
            %valreset=app.TimeleftsEditField.Value;
            %valreset=0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'main';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'Start';
            app.statenumber = uilabel(app.figure1);
            app.statenumber.Text = '0';
            app.animalid = uieditfield(app.figure1, 'text');
            app.animalid.Value = 'test';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Animal ID';
            app.dir = uibutton(app.figure1, 'push');
            app.dir.ButtonPushedFcn = createCallbackFcn(app, @dir_Callback, true);
            app.dir.Text = 'Dir';
            app.notes = uieditfield(app.figure1, 'text');
            app.notes.Value = 'test';
            app.text26 = uilabel(app.figure1);
            app.text26.Text = 'Notes';
            app.state1_count = uilabel(app.figure1);
            app.state1_count.Text = '0';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = 'State';
            app.text36 = uilabel(app.figure1);
            app.text36.Text = 'Trial #';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Valve Control';
            app.rewardL = uibutton(app.uipanel2, 'push');
            app.rewardL.ButtonPushedFcn = createCallbackFcn(app, @rewardL_Callback, true);
            app.rewardL.Text = 'Reward 1';
            app.valveR = uibutton(app.uipanel2, 'push');
            app.valveR.ButtonPushedFcn = createCallbackFcn(app, @valveRButtonPushed, true);
            app.valveR.Text = 'Open Valve R';
            app.valveL = uibutton(app.uipanel2, 'push');
            app.valveL.ButtonPushedFcn = createCallbackFcn(app, @valveLButtonPushed, true);
            app.valveL.Text = 'Open Valve L';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Text = 'reward_pulses (ms)';
            app.reward_pulse_C = uieditfield(app.uipanel2, 'text');
            app.reward_pulse_C.ValueChangedFcn = createCallbackFcn(app, @reward_pulse_C_Callback, true);
            app.reward_pulse_C.Value = '100';
            app.reward_pulse_R = uieditfield(app.uipanel2, 'text');
            app.reward_pulse_R.ValueChangedFcn = createCallbackFcn(app, @reward_pulse_R_Callback, true);
            app.reward_pulse_R.Value = '100';
            app.reward_pulse_L = uieditfield(app.uipanel2, 'text');
            app.reward_pulse_L.ValueChangedFcn = createCallbackFcn(app, @reward_pulse_L_Callback, true);
            app.reward_pulse_L.Value = '100';
            app.rewardR = uibutton(app.uipanel2, 'push');
            app.rewardR.ButtonPushedFcn = createCallbackFcn(app, @rewardR_Callback, true);
            app.rewardR.Text = 'Reward 2';
            app.rewardC = uibutton(app.uipanel2, 'push');
            app.rewardC.ButtonPushedFcn = createCallbackFcn(app, @rewardC_Callback, true);
            app.rewardC.Text = 'Reward 3';
            app.valveC = uibutton(app.uipanel2, 'push');
            app.valveC.ButtonPushedFcn = createCallbackFcn(app, @valveCButtonPushed, true);
            app.valveC.Text = 'Open Valve C';
            app.write = uibutton(app.figure1, 'push');
            app.write.ButtonPushedFcn = createCallbackFcn(app, @write_Callback, true);
            app.write.Text = 'Write Note';
            app.Panel_General = uipanel(app.figure1);
            app.ExperimentDurationsLabel = uilabel(app.Panel_General);
            app.ExperimentDurationsLabel.Text = 'Experiment Duration (s)';
            app.ex_duration = uieditfield(app.Panel_General, 'numeric');
            app.ex_duration.Value = 900;
            app.State3TimeoutsLabel = uilabel(app.Panel_General);
            app.State3TimeoutsLabel.Text = 'State3 Timeout (s)';
            app.state3_timeout = uieditfield(app.Panel_General, 'numeric');
            app.state3_timeout.Value = 30;
            app.NumberofLicksRequiredLabel = uilabel(app.Panel_General);
            app.NumberofLicksRequiredLabel.Text = 'Number of Licks Required';
            app.lick_count_requirement = uieditfield(app.Panel_General, 'numeric');
            app.lick_count_requirement.Value = 1;
            app.LickTimeRequirementLabel = uilabel(app.Panel_General);
            app.LickTimeRequirementLabel.Text = 'Lick Time Requirement';
            app.lick_time_requirement = uieditfield(app.Panel_General, 'numeric');
            app.lick_time_requirement.Value = 1;
            app.text19_2 = uilabel(app.Panel_General);
            app.text19_2.Text = 'General Controls';
            app.PercentStimLLabel = uilabel(app.Panel_General);
            app.PercentStimLLabel.Text = 'Percent StimL';
            app.percent_stim = uieditfield(app.Panel_General, 'numeric');
            app.percent_stim.Value = 50;
            app.StarttimerButton = uibutton(app.Panel_General, 'push');
            app.StarttimerButton.ButtonPushedFcn = createCallbackFcn(app, @StarttimerButtonPushed, true);
            app.StarttimerButton.Text = 'Start timer';
            app.TimeleftsEditFieldLabel = uilabel(app.Panel_General);
            app.TimeleftsEditFieldLabel.Text = 'Time left (s)';
            app.TimeleftsEditField = uieditfield(app.Panel_General, 'numeric');
            app.SessionTimeminEditFieldLabel = uilabel(app.Panel_General);
            app.SessionTimeminEditFieldLabel.Text = 'Session Time (min)';
            app.SessionTimeminEditField = uieditfield(app.Panel_General, 'numeric');
            app.StopandResettimerButton = uibutton(app.Panel_General, 'push');
            app.StopandResettimerButton.ButtonPushedFcn = createCallbackFcn(app, @StopandResettimerButtonPushed, true);
            app.StopandResettimerButton.Text = 'Stop and Reset timer';
            app.LoadSettingsButton = uibutton(app.figure1, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.figure1, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.text29_4 = uilabel(app.figure1);
            app.text29_4.Text = 'Left correct';
            app.text29_7 = uilabel(app.figure1);
            app.text29_7.Text = 'Right correct';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Tone Control';
            app.toner = uibutton(app.uipanel3, 'push');
            app.toner.ButtonPushedFcn = createCallbackFcn(app, @toner_Callback, true);
            app.toner.Text = 'R ON';
            app.tonel = uibutton(app.uipanel3, 'push');
            app.tonel.ButtonPushedFcn = createCallbackFcn(app, @tonel_Callback, true);
            app.tonel.Text = 'L  ON ';
            app.vol = uibutton(app.uipanel3, 'push');
            app.vol.ButtonPushedFcn = createCallbackFcn(app, @vol_Callback, true);
            app.vol.Text = 'VOL +';
            app.vol_2 = uibutton(app.uipanel3, 'push');
            app.vol_2.ButtonPushedFcn = createCallbackFcn(app, @vol_2ButtonPushed, true);
            app.vol_2.Text = 'VOL -';
            app.AllBackgroundButton = uibutton(app.figure1, 'push');
            app.AllBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @AllBackgroundButtonPushed, true);
            app.AllBackgroundButton.Text = 'AllBackground';
            app.EasyMode = uicheckbox(app.figure1);
            app.EasyMode.ValueChangedFcn = createCallbackFcn(app, @EasyModeValueChanged, true);
            app.EasyMode.Text = 'EasyMode (stage 1)';
            app.FollowLightMode = uicheckbox(app.figure1);
            app.FollowLightMode.ValueChangedFcn = createCallbackFcn(app, @FollowLightModeValueChanged, true);
            app.FollowLightMode.Text = 'FollowLightMode (stage 2)';
            app.TimeoutPunishmentsLabel = uilabel(app.figure1);
            app.TimeoutPunishmentsLabel.Text = 'Timeout Punishment (s)';
            app.timeout_punishment = uieditfield(app.figure1, 'numeric');
            app.timeout_punishment.Value = 10;
            app.TimeoutPunishmentCheckBox = uicheckbox(app.figure1);
            app.TimeoutPunishmentCheckBox.Text = 'Timeout Punishment';
            app.LeftSpatialFreqWidthLabel = uilabel(app.figure1);
            app.LeftSpatialFreqWidthLabel.Text = 'Left Spatial Freq. Width';
            app.sfg_L = uieditfield(app.figure1, 'numeric');
            app.sfg_L.Value = 10;
            app.RightSpatialFreqWidthLabel = uilabel(app.figure1);
            app.RightSpatialFreqWidthLabel.Text = 'Right Spatial Freq. Width';
            app.sfg_R = uieditfield(app.figure1, 'numeric');
            app.sfg_R.Value = 100;
            app.closescreenButton = uibutton(app.figure1, 'push');
            app.closescreenButton.ButtonPushedFcn = createCallbackFcn(app, @closescreenPushed, true);
            app.closescreenButton.Text = 'closescreen';
            app.PatternMatchMode = uicheckbox(app.figure1);
            app.PatternMatchMode.ValueChangedFcn = createCallbackFcn(app, @PatternModeValueChanged3, true);
            app.PatternMatchMode.Text = 'PatternMatchMode (stage 3)';
            app.pcontrast50 = uicheckbox(app.figure1);
            app.pcontrast50.ValueChangedFcn = createCallbackFcn(app, @pcontrast50ValueChanged, true);
            app.pcontrast50.Text = '50% contrast mode';
            app.pcontrast75 = uicheckbox(app.figure1);
            app.pcontrast75.ValueChangedFcn = createCallbackFcn(app, @pcontrast75ValueChanged, true);
            app.pcontrast75.Text = '75% contrast mode';
            app.pcontrast95 = uicheckbox(app.figure1);
            app.pcontrast95.ValueChangedFcn = createCallbackFcn(app, @pcontrast95ValueChanged, true);
            app.pcontrast95.Text = '95% contrast mode';
            app.pcontrast80 = uicheckbox(app.figure1);
            app.pcontrast80.ValueChangedFcn = createCallbackFcn(app, @pcontrast80ValueChanged, true);
            app.pcontrast80.Text = '80% contrast mode';
            app.pcontrast85 = uicheckbox(app.figure1);
            app.pcontrast85.ValueChangedFcn = createCallbackFcn(app, @pcontrast85ValueChanged, true);
            app.pcontrast85.Text = '85% contrast mode';
            app.pcontrast90 = uicheckbox(app.figure1);
            app.pcontrast90.ValueChangedFcn = createCallbackFcn(app, @pcontrast90ValueChanged, true);
            app.pcontrast90.Text = '90% contrast mode';
            app.AudioMode = uicheckbox(app.figure1);
            app.AudioMode.ValueChangedFcn = createCallbackFcn(app, @AudioModeValueChanged, true);
            app.AudioMode.Text = 'AudioMode (stage 4)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Decision_MakingB

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end