classdef Decision_MakingCopy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        start                          matlab.ui.control.Button
        statenumber                    matlab.ui.control.Label
        animalid                       matlab.ui.control.EditField
        text19                         matlab.ui.control.Label
        dir                            matlab.ui.control.Button
        notes                          matlab.ui.control.EditField
        text26                         matlab.ui.control.Label
        state1_count                   matlab.ui.control.Label
        text29                         matlab.ui.control.Label
        text36                         matlab.ui.control.Label
        uipanel2                       matlab.ui.container.Panel
        rewardL                        matlab.ui.control.Button
        valveR                         matlab.ui.control.Button
        valveL                         matlab.ui.control.Button
        rewardR                        matlab.ui.control.Button
        rewardC                        matlab.ui.control.Button
        valveC                         matlab.ui.control.Button
        text4                          matlab.ui.control.Label
        reward_pulse_C                 matlab.ui.control.EditField
        reward_pulse_R                 matlab.ui.control.EditField
        reward_pulse_L                 matlab.ui.control.EditField
        uipanel3                       matlab.ui.container.Panel
        toner                          matlab.ui.control.Button
        tonel                          matlab.ui.control.Button
        vol                            matlab.ui.control.Button
        vol_2                          matlab.ui.control.Button
        uipanel4                       matlab.ui.container.Panel
        flashl                         matlab.ui.control.Button
        flashr                         matlab.ui.control.Button
        flashc                         matlab.ui.control.Button
        test                           matlab.ui.control.Button
        allleds                        matlab.ui.control.CheckBox
        uipanel5                       matlab.ui.container.Panel
        airpuff                        matlab.ui.control.Button
        write                          matlab.ui.control.Button
        spout1_1                       matlab.ui.control.Button
        spout2_1                       matlab.ui.control.Button
        fullretract                    matlab.ui.control.Button
        torest                         matlab.ui.control.Button
        sp1rest                        matlab.ui.control.EditField
        text15                         matlab.ui.control.Label
        sp1ext                         matlab.ui.control.EditField
        text16                         matlab.ui.control.Label
        sp2rest                        matlab.ui.control.EditField
        text17                         matlab.ui.control.Label
        sp2ext                         matlab.ui.control.EditField
        text18                         matlab.ui.control.Label
        fullout                        matlab.ui.control.Button
        cclock                         matlab.ui.control.Button
        text19_3                       matlab.ui.control.Label
        Panel_TDC                      matlab.ui.container.Panel
        StimStartDelaysLabel           matlab.ui.control.Label
        stim_start_delay               matlab.ui.control.NumericEditField
        TimeCenterSpoutAvailableLabel  matlab.ui.control.Label
        time_center_spout_available    matlab.ui.control.NumericEditField
        text29_2                       matlab.ui.control.Label
        Stable_Delays                  matlab.ui.control.CheckBox
        Variable_TCSA                  matlab.ui.control.CheckBox
        Variable_SSD                   matlab.ui.control.CheckBox
        StimStartDelayLabel            matlab.ui.control.Label
        stim_start_delay_fixed         matlab.ui.control.NumericEditField
        TimeCenterSpoutAvailableLabel_2  matlab.ui.control.Label
        time_center_spout_available_fixed  matlab.ui.control.NumericEditField
        tcsa_reward                    matlab.ui.control.CheckBox
        DelayTableLabel                matlab.ui.control.Label
        minsEditField_2Label           matlab.ui.control.Label
        min_delay                      matlab.ui.control.NumericEditField
        maxsEditField_2Label           matlab.ui.control.Label
        max_delay                      matlab.ui.control.NumericEditField
        numberofstepsEditField_2Label  matlab.ui.control.Label
        numberofsteps                  matlab.ui.control.NumericEditField
        override                       matlab.ui.control.CheckBox
        per_R                          matlab.ui.control.NumericEditField
        PercentageofRightRewardsLabel  matlab.ui.control.Label
        percentage_of_training_trials  matlab.ui.control.NumericEditField
        PercentageTrainingTrialsLabel  matlab.ui.control.Label
        first_lick_no_reward           matlab.ui.control.CheckBox
        TCSALickTimeWindowsLabel       matlab.ui.control.Label
        tcsa_lick_time_window          matlab.ui.control.NumericEditField
        Panel_DM                       matlab.ui.container.Panel
        ButtonGroup_DM                 matlab.ui.container.ButtonGroup
        Light                          matlab.ui.control.RadioButton
        Tone                           matlab.ui.control.RadioButton
        Noise                          matlab.ui.control.RadioButton
        HzLabel                        matlab.ui.control.Label
        DM_tone_freq                   matlab.ui.control.NumericEditField
        BrightnessVolumeLabel          matlab.ui.control.Label
        DM_brightness_volume           matlab.ui.control.NumericEditField
        RateDiscrimination             matlab.ui.control.CheckBox
        light2                         matlab.ui.control.CheckBox
        light8                         matlab.ui.control.CheckBox
        no_test_punishment             matlab.ui.control.CheckBox
        SideDiscrimination             matlab.ui.control.CheckBox
        HighRateHzLabel                matlab.ui.control.Label
        high_rate                      matlab.ui.control.NumericEditField
        LowRateHzLabel                 matlab.ui.control.Label
        low_rate                       matlab.ui.control.NumericEditField
        DurationsLabel                 matlab.ui.control.Label
        stim_duration_rate_disc        matlab.ui.control.NumericEditField
        higher_rate_correct            matlab.ui.control.CheckBox
        Stim_durationsLabel            matlab.ui.control.Label
        stim_duration                  matlab.ui.control.NumericEditField
        Stim_cycle_durationsLabel      matlab.ui.control.Label
        stim_cycle_duration            matlab.ui.control.NumericEditField
        NumberofstimcyclesLabel        matlab.ui.control.Label
        number_of_stim_cycles          matlab.ui.control.NumericEditField
        two_rates_only                 matlab.ui.control.CheckBox
        mixed_rates                    matlab.ui.control.CheckBox
        no_test_punishment_2           matlab.ui.control.CheckBox
        MaxRatioLabel                  matlab.ui.control.Label
        max_ratio                      matlab.ui.control.NumericEditField
        MixedRatesStepsDropDownLabel   matlab.ui.control.Label
        mixed_rates_steps              matlab.ui.control.DropDown
        single_panel                   matlab.ui.control.CheckBox
        Panel_General                  matlab.ui.container.Panel
        ExperimentDurationsLabel       matlab.ui.control.Label
        ex_duration                    matlab.ui.control.NumericEditField
        State1TimeoutsLabel            matlab.ui.control.Label
        state1_timeout                 matlab.ui.control.NumericEditField
        State3TimeoutsLabel            matlab.ui.control.Label
        state3_timeout                 matlab.ui.control.NumericEditField
        NumberofLicksRequiredLabel     matlab.ui.control.Label
        lick_count_requirement         matlab.ui.control.NumericEditField
        LickTimeRequirementLabel       matlab.ui.control.Label
        lick_time_requirement          matlab.ui.control.NumericEditField
        text19_2                       matlab.ui.control.Label
        Waitbeforestate2or4Label       matlab.ui.control.Label
        wait24                         matlab.ui.control.NumericEditField
        timeout_punishment             matlab.ui.control.CheckBox
        punishment_active              matlab.ui.control.CheckBox
        AirPuffmsLabel                 matlab.ui.control.Label
        airpuff_duration               matlab.ui.control.NumericEditField
        PunishmentToneTimesLabel       matlab.ui.control.Label
        punishment_tone_time           matlab.ui.control.NumericEditField
        PunishmentRestTimesLabel       matlab.ui.control.Label
        punishment_rest_time           matlab.ui.control.NumericEditField
        BiasCorroftestsLabel           matlab.ui.control.Label
        bias_buffer                    matlab.ui.control.NumericEditField
        BiasCorrdiffLabel              matlab.ui.control.Label
        bias_diff                      matlab.ui.control.NumericEditField
        retrial_mode                   matlab.ui.control.CheckBox
        training_reward                matlab.ui.control.CheckBox
        free_reward                    matlab.ui.control.CheckBox
        biason                         matlab.ui.control.CheckBox
        LoadSettingsButton             matlab.ui.control.Button
        SaveSettingsButton             matlab.ui.control.Button
        text29_4                       matlab.ui.control.Label
        text29_7                       matlab.ui.control.Label
    end

    
    methods (Access = private)


        function saveState(app,fn)
            
            h = findobj(app.figure1,'Type','uicheckbox');
            xarr = zeros(length(h),1);
            for i = 1:length(h)
               xarr(i) = h(i).Value;
            end
            state.checkboxes = xarr;
            
            
             h = findobj(app.figure1,'Type','uieditfield');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.editfields = xa;
             
             h = findobj(app.figure1,'Type','uinumericeditfield');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.uinumericeditfields = xa;
             
             h = findobj(app.figure1,'Type','uiradiobutton');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.uiradiobuttons = xa;
             
             h = findobj(app.figure1,'Type','uiDropDown');
             for i = 1:length(h)
                xa{i} = h(i).Value;
             end
             state.dropdowns = xa;
             
           ps = app.figure1.Position;
           save(fn,'state','ps');
        end

        
        function loadState(app,fn)
%                return
%             load('MyAppDefaultValues.mat','state');
            load(fn);
            
            h = findobj(app.figure1,'Type','uicheckbox');
            for i = 1:length(h)
               h(i).Value = state.checkboxes(i);
            end
            
             h = findobj(app.figure1,'Type','uieditfield');
             for i = 1:length(h)
                h(i).Value = state.editfields{i};
             end
             
             h = findobj(app.figure1,'Type','uinumericeditfield');
             for i = 1:length(h)
                h(i).Value = state.uinumericeditfields{i};
             end
             
             
             h = findobj(app.figure1,'Type','uiradiobutton');
              for i = 1:length(h)
                 h(i).Value = state.uiradiobuttons{i};
              end
              
             h = findobj(app.figure1,'Type','uiDropDown');
              for i = 1:length(h)
                 h(i).Value = state.dropdowns{i};
              end
              
             app.figure1.Position = ps;

        end        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app)
            
            
              addpath(genpath(['.' filesep 'general' filesep]));
              addpath(genpath(['.' filesep 'DM' filesep]));
              addpath(genpath(['.' filesep 'SS' filesep]));

              app.loadState('gui_settings.mat');

%               app.figure1.Position = [800 500 1216 755];
              app.figure1.Name = 'Mouse40';
%               app.start.Enable = 'off';
              app.start.Visible = 'off';
              init(app);
              app.start.Visible = 'on';
              app.start.Enable = 'on';

        end

        % Button pushed function: airpuff
        function airpuff_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c','d');  %r
            fprintf('%4.3f air_puff \n',toc);
        end

        % Callback function
        function airpuff_duration_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['u' round(app.airpuff_duration.Value)]);
        end

        % Button pushed function: cclock
        function cclock_Callback(app, event)
            global arduino;
            
            if contains(app.cclock.Text,'Start')
                fprintf(arduino,'%c','(');
                app.cclock.Text = 'Stop Camera Clock';
            else
                fprintf(arduino,'%c',')');
                app.cclock.Text = 'Start Camera Clock';
            end
        end

        % Button pushed function: dir
        function dir_Callback(app, event)
            global dirpath;
            if isempty(dirpath)
                dirpath = pwd;
            end
            ddir = uigetdir(dirpath);
            
            app.animalid.Value = [ddir '\'];
        end

        % Button pushed function: flashc
        function flashc_Callback(app, event)
            if app.allleds.Value
                light_Callback(1,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(1,7);
            else
            light_Callback(1,0);
            pause(0.25);
            light_Callback(1,7);  % turn off
            light_Callback(1,1);
            pause(0.25);
            light_Callback(1,7);  % turn off
            end
        end

        % Button pushed function: flashl
        function flashl_Callback(app, event)
            if  app.allleds.Value
                light_Callback(0,5);
                light_Callback(0,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(0,7);
            elseif app.light2.Value
                light_Callback(0,5);
                pause(0.25);
                light_Callback(0,7);
            else
                light_Callback(0,5);
                pause(0.25);
                light_Callback(0,7);  % turn off
                light_Callback(0,0);
                pause(0.25);
                light_Callback(0,7);  % turn off
                light_Callback(0,1);
                pause(0.25);
                light_Callback(0,7);  % turn off
            end
        end

        % Button pushed function: flashr
        function flashr_Callback(app, event)
            if  app.allleds.Value
                light_Callback(2,5);
                light_Callback(2,4);  % page 4 all leds on
                pause(0.25);
                light_Callback(2,7);
            elseif  app.light2.Value
                light_Callback(2,5);
                pause(0.25);
                light_Callback(2,7); % turn off
            else
                light_Callback(2,5);
                pause(0.25);
                light_Callback(2,7); % turn off
                light_Callback(2,1);
                pause(0.25);
                light_Callback(2,7); % turn off
                light_Callback(2,0);
                pause(0.25);
                light_Callback(2,7);  % turn off
            end
        end

        % Button pushed function: fullout
        function fullout_Callback(app, event)
            global spout1pos spout2pos;
            global current_servo_position;
            
            control_spouts(spout1pos.ext,spout2pos.ext);
            current_servo_position =  [spout1pos.ext,spout2pos.ext];
        end

        % Button pushed function: fullretract
        function fullretract_Callback(app, event)
            
            % fully retract both spouts
            global current_servo_position;
            
            control_spouts(0,0);
            current_servo_position = [0,0];
        end

        % Value changed function: light2
        function light2_Callback(app, event)
            if app.light2.Value
               app.light2.Value = 1;
               app.light8.Value = 0;
            else
               app.light2.Value = 0;
               app.light8.Value = 1;
            end
        end

        % Value changed function: light8
        function light8_Callback(app, event)
            if app.light8.Value
               app.light2.Value = 0;
               app.light8.Value = 1;
            else
               app.light2.Value = 1;
               app.light8.Value = 0;
            end
        end

        % Callback function
        function per_R_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['w' round(app.reward_pulse_R.Value)]);
        end

        % Button pushed function: rewardC
        function rewardC_Callback(app, event)
            
            reward_Callback(app,'C');
        end

        % Button pushed function: rewardL
        function rewardL_Callback(app, event)
            
            reward_Callback(app,'L');
        end

        % Button pushed function: rewardR
        function rewardR_Callback(app, event)
            reward_Callback(app,'R');

        end

        % Value changed function: reward_pulse_C
        function reward_pulse_C_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['q' round(str2double(app.reward_pulse_C.Value))]);
        end

        % Value changed function: reward_pulse_L
        function reward_pulse_L_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['v' round(str2double(app.reward_pulse_L.Value))]);
        end

        % Value changed function: reward_pulse_R
        function reward_pulse_R_Callback(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['w' round(str2double(app.reward_pulse_R.Value))]);
        end

        % Value changed function: sp1ext
        function sp1ext_Callback(app, event)
            global spout1pos;
            spout1pos.ext = str2double(app.sp1ext.Value);
        end

        % Value changed function: sp1rest
        function sp1rest_Callback(app, event)
            global spout1pos;
            spout1pos.rest = str2double(app.sp1rest.Value);
        end

        % Value changed function: sp2ext
        function sp2ext_Callback(app, event)
            global spout2pos;
            spout2pos.ext = str2double(app.sp2ext.Value);
        end

        % Value changed function: sp2rest
        function sp2rest_Callback(app, event)
            global spout2pos;
            spout2pos.rest = str2double(app.sp2rest.Value);
        end

        % Button pushed function: spout1_1
        function spout1(app, event)
            global spout1pos;
            global s1 s2 current_servo_position;
            
            if contains(app.spout1_1.Text,'Out')
                s1 = spout1pos.ext;
                fprintf('Spout_C to ext at %4.3f\n',toc);
                app.spout1_1.Text = 'Spout C In';
            else
                s1 = spout1pos.rest;
                fprintf('Spout_C to rest at %4.3f\n',toc);
                app.spout1_1.Text = 'Spout C Out';
            end
            s2 = current_servo_position(2);
            control_spouts(s1,s2);
            current_servo_position = [s1,s2];
        end

        % Button pushed function: spout2_1
        function spout2(app, event)
            global spout2pos;
            global s1 s2 current_servo_position;
            
            if contains(app.spout2_1.Text,'Out')
                s2 = spout2pos.ext;
                fprintf('Spout_LR to ext at %4.3f\n',toc);
                app.spout2_1.Text = 'Spout LR In';
            else
                s2 = spout2pos.rest;
                fprintf('Spout_LR to rest at %4.3f\n',toc);
                app.spout2_1.Text = 'Spout LR Out';
            end
               s1 = current_servo_position(1);
               control_spouts(s1,s2);
               current_servo_position = [s1,s2];
        end

        % Callback function
        function stop_Callback(app, event)
            global run;
            
            run = 0;
        end

        % Button pushed function: test
        function test_Callback(app, event)
            
            % light_Callback(hObject, eventdata, handles,0,7);
            % pause(0.25);
            light_Callback(0,0);
            pause(0.25);
            light_Callback(0,7); % turn off
            light_Callback(0,1);
            pause(0.25);
            light_Callback(0,7); % turn off
            light_Callback(1,0);
            pause(0.25);
             light_Callback(1,7); % turn off
            light_Callback(1,1);
            pause(0.25);
            light_Callback(1,7); % turn off
            light_Callback(2,0);
            pause(0.25);
            light_Callback(2,7); % turn off
            light_Callback(2,1);
            pause(0.25);
            light_Callback(2,7);
        end

        % Button pushed function: tonel
        function tonel_Callback(app, event)
            global arduino;
            if strfind(app.tonel.Text,'ON') ~= 0
%                fprintf(arduino,'%c','=');
               tf = app.tonefreq.Value;
               btl = mod(tf,256); 
               bth = floor(tf/256);
%                fprintf(arduino,'%c',char(btl));
%                fprintf(arduino,'%c',char(bth));
%                fprintf(arduino,'%c','e');
%                fprintf(arduino,'%c',1);  % on
               app.tonel.Text = 'L OFF';
               
               fprintf(arduino,'%c',['=' btl (bth) 'e' 1]);
               
            else
%                fprintf(arduino,'%c','e');
               fprintf(arduino,'%c',['e' 0]);  % off
                app.tonel.Text = 'L ON';
            end
        end

        % Button pushed function: toner
        function toner_Callback(app, event)
            global arduino;
            if strfind(app.toner.Text,'ON') ~= 0
%                fprintf(arduino,'%c','=');
               tf = app.tonefreq.Value;
               btl = mod(tf,256); 
               bth = floor(tf/256);
%                fprintf(arduino,'%c',char(btl));
%                fprintf(arduino,'%c',char(bth));
%                fprintf(arduino,'%c','f');
%                fprintf(arduino,'%c',1);  % on
               app.toner.Text = 'R OFF';
               fprintf(arduino,'%c',['=' char(btl) char(bth) 'f' 1]);
            else
%                 fprintf(arduino,'%c','f');
                fprintf(arduino,'%c',['f' 0]);  % off
                app.toner.Text = 'R ON';
            end
        end

        % Button pushed function: torest
        function torest_Callback(app, event)
            global spout1pos spout2pos;
            global current_servo_position;
            % set bit spouts to rest position
            control_spouts(spout1pos.rest,spout2pos.rest);
            current_servo_position = [spout1pos.rest,spout2pos.rest];
        end

        % Value changed function: retrial_mode
        function retrial_mode_Callback(app, event)
            if ~app.retrial_mode.Value
                app.training_reward.Value = 0;
            end
        end

        % Value changed function: training_reward
        function training_reward_Callback(app, event)
            
            if app.training_reward.Value
                app.retrial_mode.Value = 1;
            end
        end

        % Button pushed function: vol
        function vol_Callback(app, event)
            global arduino;
            global audio_volume;
            
%             fprintf(arduino,'%c','a');
            audio_volume = audio_volume + 1;
            audio_volume = min(audio_volume,63);
            fprintf(arduino,'%c',['a' audio_volume]);
            fprintf('audio volume = %d \n',audio_volume);
            
            
        end

        % Button pushed function: write
        function write_Callback(app, event)
            global notes notestime write_index;
            
            notes{write_index} = app.notes.Value;
            notestime{write_index} = toc;
            
            write_index = write_index + 1;
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            global run; 

            app.start.Text = 'Start';
            if run
                run = 0;
                return;
            end
                
            app.start.Text = 'Stop';        
            run = 1;                      
            start_Callback(app);

        end

        % Button pushed function: valveL
        function valveLButtonPushed(app, event)
           
            global arduino;
%             fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',0);
            if contains(app.valveL.Text,'Open')
                fprintf(arduino,'%c',['k' 0 1]);
                app.valveL.Text = 'Close Valve L';
            else
                fprintf(arduino,'%c',['k' 0 0]);
                app.valveL.Text = 'Open Valve L';
            end
            
        end

        % Button pushed function: valveC
        function valveCButtonPushed(app, event)
            
            global arduino;
            fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',1);
            if contains(app.valveC.Text,'Open')
                fprintf(arduino,'%c',['k' 1 1]);
                app.valveC.Text = 'Close Valve C';
            else
                fprintf(arduino,'%c',['k' 1 0]);
                app.valveC.Text = 'Open Valve C';
            end
            
        end

        % Button pushed function: valveR
        function valveRButtonPushed(app, event)
            
            global arduino;
%             fprintf(arduino,'%c','k');
%             fprintf(arduino,'%c',2);
            if contains(app.valveR.Text,'Open')
                fprintf(arduino,'%c',['k' 2 1]);
                app.valveR.Text = 'Close Valve R';
            else
                fprintf(arduino,'%c',['k' 2 0]);
                app.valveR.Text = 'Open Valve R';
            end
            
        end

        % Callback function
        function freqValueChanged(app, event)
            global arduino;
            
            fprintf(arduino,'%c',['{' round(app.freq.Value)]);
            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            
              app.saveState('gui_settings.mat');
              delete(app);
              delete(instrfindall);
            
        end

        % Callback function
        function brightnessValueChanged(app, event)
            global arduino;
            
%             fprintf(arduino,'%c','}');
%             fprintf(arduino,'%c',round(app.brightness.Value));
%             fprintf(arduino,'%c',0); % do not initialize panels
            fprintf(arduino,'%c',['}' round(app.brightness.Value) 0]); % do not initialize panels
            
            
        end

        % Button pushed function: vol_2
        function vol_2ButtonPushed(app, event)
            global arduino;
            global audio_volume;
            
%             fprintf(arduino,'%c','a');
            audio_volume = audio_volume - 1;
            audio_volume = max(audio_volume,0);
%             fprintf(arduino,'%c',audio_volume);
            fprintf(arduino,'%c',['a' audio_volume]);
            fprintf('audio volume = %d \n',audio_volume);
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            global run;
            run = 0;
        end

        % Callback function
        function DMValueChanged3(app, event)
            app.DM.Value = 1;
            app.SS.Value = 0;
            set_backgrounds(app);
            
        end

        % Callback function
        function SSValueChanged(app, event)
            app.SS.Value = 1;
            app.DM.Value = 0;
            set_backgrounds(app);
            
        end

        % Value changed function: SideDiscrimination
        function SideDiscriminationValueChanged(app, event)
            value = app.SideDiscrimination.Value;
            app.RateDiscrimination.Value = ~value;
            
        end

        % Value changed function: Variable_SSD
        function Variable_SSDValueChanged(app, event)
            app.Variable_SSD.Value = 1;
            app.Variable_TCSA.Value = 0;
            app.Stable_Delays.Value = 0;
            
        end

        % Value changed function: Variable_TCSA
        function Variable_TCSAValueChanged(app, event)
            app.Variable_SSD.Value = 0;
            app.Variable_TCSA.Value = 1;
            app.Stable_Delays.Value = 0;
            
        end

        % Value changed function: RateDiscrimination
        function RateDiscriminationValueChanged(app, event)
            value = app.RateDiscrimination.Value;
            app.SideDiscrimination.Value = ~value;

        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            global settings_path;
            
            [fn,pn,FilterIndex]  = ...
                 uiputfile({'*.mat'},'Input data file',setttings_path);
            if (pn ~= 0)
                settings_path = pn;
            else
                 return;
            end
            app.saveState([pn filesep fn]);

        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            global settings_path;
            
            [fn,pn,FilterIndex]  = ...
                 uigetfile({'*.mat'},'Input data file',settings_path);
            if (pn ~= 0)
                settings_path = pn;
            else
                 return;
            end
            app.loadState([pn filesep fn]);
            
        end

        % Callback function
        function SmallPanelCheckBoxValueChanged(app, event)
            app.SmallPanelCheckBox.Value = 1;
            app.FullpanelCheckBox.Value = 0;
            app.BarsCheckBox.Value = 0;
        end

        % Callback function
        function FullpanelCheckBoxValueChanged(app, event)
            app.FullpanelCheckBox.Value = 1;
            app.SmallPanelCheckBox.Value = 0;
            app.BarsCheckBox.Value = 0;
        end

        % Callback function
        function BarsCheckBoxValueChanged(app, event)
            app.BarsCheckBox.Value = 1;
            app.SmallPanelCheckBox.Value = 0;
            app.FullpanelCheckBox.Value = 0;

        end

        % Value changed function: Stable_Delays
        function Stable_DelaysValueChanged(app, event)
            app.Variable_SSD.Value = 0;
            app.Variable_TCSA.Value = 0;
            app.Stable_Delays.Value = 1;
            
        end

        % Value changed function: two_rates_only
        function two_rates_onlyValueChanged(app, event)
            app.two_rates_only.Value = 1;
            app.mixed_rates.Value = 0;

        end

        % Value changed function: mixed_rates
        function mixed_ratesValueChanged(app, event)
            app.mixed_rates.Value = 1;
            app.two_rates_only.Value = 0;
            
        end

        % Callback function
        function ButtonPushed(app, event)
            start_stimulus_with_delay(app);
        end

        % Value changed function: DM_brightness_volume
        function DM_brightness_volumeValueChanged(app, event)
            value = app.DM_brightness_volume.Value;
            global arduino;
            v  = event.Value;
            fprintf(arduino,'%c',['}' v 1]);  % 

            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'main';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = 'Start';
            app.statenumber = uilabel(app.figure1);
            app.statenumber.Text = '0';
            app.animalid = uieditfield(app.figure1, 'text');
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Animal ID';
            app.dir = uibutton(app.figure1, 'push');
            app.dir.ButtonPushedFcn = createCallbackFcn(app, @dir_Callback, true);
            app.dir.Text = 'Dir';
            app.notes = uieditfield(app.figure1, 'text');
            app.text26 = uilabel(app.figure1);
            app.text26.Text = 'Notes';
            app.state1_count = uilabel(app.figure1);
            app.state1_count.Text = '0';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = 'State';
            app.text36 = uilabel(app.figure1);
            app.text36.Text = 'Trial #';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Valve Control';
            app.rewardL = uibutton(app.uipanel2, 'push');
            app.rewardL.ButtonPushedFcn = createCallbackFcn(app, @rewardL_Callback, true);
            app.rewardL.Text = 'Reward L';
            app.valveR = uibutton(app.uipanel2, 'push');
            app.valveR.ButtonPushedFcn = createCallbackFcn(app, @valveRButtonPushed, true);
            app.valveR.Text = 'Open Valve R';
            app.valveL = uibutton(app.uipanel2, 'push');
            app.valveL.ButtonPushedFcn = createCallbackFcn(app, @valveLButtonPushed, true);
            app.valveL.Text = 'Open Valve L';
            app.rewardR = uibutton(app.uipanel2, 'push');
            app.rewardR.ButtonPushedFcn = createCallbackFcn(app, @rewardR_Callback, true);
            app.rewardR.Text = 'Reward R';
            app.rewardC = uibutton(app.uipanel2, 'push');
            app.rewardC.ButtonPushedFcn = createCallbackFcn(app, @rewardC_Callback, true);
            app.rewardC.Text = 'Reward C';
            app.valveC = uibutton(app.uipanel2, 'push');
            app.valveC.ButtonPushedFcn = createCallbackFcn(app, @valveCButtonPushed, true);
            app.valveC.Text = 'Open Valve C';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Text = 'reward_pulses (ms)';
            app.reward_pulse_C = uieditfield(app.uipanel2, 'text');
            app.reward_pulse_C.ValueChangedFcn = createCallbackFcn(app, @reward_pulse_C_Callback, true);
            app.reward_pulse_C.Value = '100';
            app.reward_pulse_R = uieditfield(app.uipanel2, 'text');
            app.reward_pulse_R.ValueChangedFcn = createCallbackFcn(app, @reward_pulse_R_Callback, true);
            app.reward_pulse_R.Value = '100';
            app.reward_pulse_L = uieditfield(app.uipanel2, 'text');
            app.reward_pulse_L.ValueChangedFcn = createCallbackFcn(app, @reward_pulse_L_Callback, true);
            app.reward_pulse_L.Value = '100';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Tone Control';
            app.toner = uibutton(app.uipanel3, 'push');
            app.toner.ButtonPushedFcn = createCallbackFcn(app, @toner_Callback, true);
            app.toner.Text = 'R ON';
            app.tonel = uibutton(app.uipanel3, 'push');
            app.tonel.ButtonPushedFcn = createCallbackFcn(app, @tonel_Callback, true);
            app.tonel.Text = 'L  ON ';
            app.vol = uibutton(app.uipanel3, 'push');
            app.vol.ButtonPushedFcn = createCallbackFcn(app, @vol_Callback, true);
            app.vol.Text = 'VOL +';
            app.vol_2 = uibutton(app.uipanel3, 'push');
            app.vol_2.ButtonPushedFcn = createCallbackFcn(app, @vol_2ButtonPushed, true);
            app.vol_2.Text = 'VOL -';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Light Control';
            app.flashl = uibutton(app.uipanel4, 'push');
            app.flashl.ButtonPushedFcn = createCallbackFcn(app, @flashl_Callback, true);
            app.flashl.Text = 'Flash L';
            app.flashr = uibutton(app.uipanel4, 'push');
            app.flashr.ButtonPushedFcn = createCallbackFcn(app, @flashr_Callback, true);
            app.flashr.Text = 'Flash R';
            app.flashc = uibutton(app.uipanel4, 'push');
            app.flashc.ButtonPushedFcn = createCallbackFcn(app, @flashc_Callback, true);
            app.flashc.Text = 'Flash C';
            app.test = uibutton(app.uipanel4, 'push');
            app.test.ButtonPushedFcn = createCallbackFcn(app, @test_Callback, true);
            app.test.Text = 'Light Sequence';
            app.allleds = uicheckbox(app.uipanel4);
            app.allleds.Text = 'All LED Pattern';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Air Puff';
            app.airpuff = uibutton(app.uipanel5, 'push');
            app.airpuff.ButtonPushedFcn = createCallbackFcn(app, @airpuff_Callback, true);
            app.airpuff.Text = 'Send Air Puff';
            app.write = uibutton(app.figure1, 'push');
            app.write.ButtonPushedFcn = createCallbackFcn(app, @write_Callback, true);
            app.write.Text = 'Write Note';
            app.spout1_1 = uibutton(app.figure1, 'push');
            app.spout1_1.ButtonPushedFcn = createCallbackFcn(app, @spout1, true);
            app.spout1_1.Text = 'C Out';
            app.spout2_1 = uibutton(app.figure1, 'push');
            app.spout2_1.ButtonPushedFcn = createCallbackFcn(app, @spout2, true);
            app.spout2_1.Text = 'LR Out';
            app.fullretract = uibutton(app.figure1, 'push');
            app.fullretract.ButtonPushedFcn = createCallbackFcn(app, @fullretract_Callback, true);
            app.fullretract.Text = 'FullRetract';
            app.torest = uibutton(app.figure1, 'push');
            app.torest.ButtonPushedFcn = createCallbackFcn(app, @torest_Callback, true);
            app.torest.Text = 'ToRest';
            app.sp1rest = uieditfield(app.figure1, 'text');
            app.sp1rest.ValueChangedFcn = createCallbackFcn(app, @sp1rest_Callback, true);
            app.sp1rest.Value = '11';
            app.text15 = uilabel(app.figure1);
            app.text15.Text = 'Spout1_Rest';
            app.sp1ext = uieditfield(app.figure1, 'text');
            app.sp1ext.ValueChangedFcn = createCallbackFcn(app, @sp1ext_Callback, true);
            app.sp1ext.Value = '16';
            app.text16 = uilabel(app.figure1);
            app.text16.Text = 'Spout1_Ext';
            app.sp2rest = uieditfield(app.figure1, 'text');
            app.sp2rest.ValueChangedFcn = createCallbackFcn(app, @sp2rest_Callback, true);
            app.sp2rest.Value = '11';
            app.text17 = uilabel(app.figure1);
            app.text17.Text = 'Spout2_Rest';
            app.sp2ext = uieditfield(app.figure1, 'text');
            app.sp2ext.ValueChangedFcn = createCallbackFcn(app, @sp2ext_Callback, true);
            app.sp2ext.Value = '16';
            app.text18 = uilabel(app.figure1);
            app.text18.Text = 'Spout2_Ext';
            app.fullout = uibutton(app.figure1, 'push');
            app.fullout.ButtonPushedFcn = createCallbackFcn(app, @fullout_Callback, true);
            app.fullout.Text = 'Full Out';
            app.cclock = uibutton(app.figure1, 'push');
            app.cclock.ButtonPushedFcn = createCallbackFcn(app, @cclock_Callback, true);
            app.cclock.Text = 'Start Camera';
            app.text19_3 = uilabel(app.figure1);
            app.text19_3.Text = 'Servo Controls';
            app.Panel_TDC = uipanel(app.figure1);
            app.StimStartDelaysLabel = uilabel(app.Panel_TDC);
            app.StimStartDelaysLabel.Text = 'Stim Start Delay (s)';
            app.stim_start_delay = uieditfield(app.Panel_TDC, 'numeric');
            app.stim_start_delay.Value = 0.5;
            app.TimeCenterSpoutAvailableLabel = uilabel(app.Panel_TDC);
            app.TimeCenterSpoutAvailableLabel.Text = 'Time Center Spout Available';
            app.time_center_spout_available = uieditfield(app.Panel_TDC, 'numeric');
            app.time_center_spout_available.Value = 0.1;
            app.text29_2 = uilabel(app.Panel_TDC);
            app.text29_2.Text = 'Timer and Delay Controls';
            app.Stable_Delays = uicheckbox(app.Panel_TDC);
            app.Stable_Delays.ValueChangedFcn = createCallbackFcn(app, @Stable_DelaysValueChanged, true);
            app.Stable_Delays.Text = 'Stable Delays';
            app.Variable_TCSA = uicheckbox(app.Panel_TDC);
            app.Variable_TCSA.ValueChangedFcn = createCallbackFcn(app, @Variable_TCSAValueChanged, true);
            app.Variable_TCSA.Text = 'Variable Time Center Spout Available';
            app.Variable_SSD = uicheckbox(app.Panel_TDC);
            app.Variable_SSD.ValueChangedFcn = createCallbackFcn(app, @Variable_SSDValueChanged, true);
            app.Variable_SSD.Text = 'Variable Stim Start Delay';
            app.StimStartDelayLabel = uilabel(app.Panel_TDC);
            app.StimStartDelayLabel.Text = 'Stim Start Delay';
            app.stim_start_delay_fixed = uieditfield(app.Panel_TDC, 'numeric');
            app.stim_start_delay_fixed.Value = 1;
            app.TimeCenterSpoutAvailableLabel_2 = uilabel(app.Panel_TDC);
            app.TimeCenterSpoutAvailableLabel_2.Text = 'Time Center Spout Available';
            app.time_center_spout_available_fixed = uieditfield(app.Panel_TDC, 'numeric');
            app.time_center_spout_available_fixed.Value = 1;
            app.tcsa_reward = uicheckbox(app.Panel_TDC);
            app.tcsa_reward.Text = 'TCSA reward';
            app.DelayTableLabel = uilabel(app.Panel_TDC);
            app.DelayTableLabel.Text = 'Delay Table:';
            app.minsEditField_2Label = uilabel(app.Panel_TDC);
            app.minsEditField_2Label.Text = 'min (s)';
            app.min_delay = uieditfield(app.Panel_TDC, 'numeric');
            app.maxsEditField_2Label = uilabel(app.Panel_TDC);
            app.maxsEditField_2Label.Text = 'max (s)';
            app.max_delay = uieditfield(app.Panel_TDC, 'numeric');
            app.numberofstepsEditField_2Label = uilabel(app.Panel_TDC);
            app.numberofstepsEditField_2Label.Text = 'number of steps';
            app.numberofsteps = uieditfield(app.Panel_TDC, 'numeric');
            app.override = uicheckbox(app.Panel_TDC);
            app.override.Text = 'Override Consec Constraint';
            app.per_R = uieditfield(app.Panel_TDC, 'numeric');
            app.per_R.Value = 50;
            app.PercentageofRightRewardsLabel = uilabel(app.Panel_TDC);
            app.PercentageofRightRewardsLabel.Text = 'Percentage of Right Rewards';
            app.percentage_of_training_trials = uieditfield(app.Panel_TDC, 'numeric');
            app.percentage_of_training_trials.Value = 100;
            app.PercentageTrainingTrialsLabel = uilabel(app.Panel_TDC);
            app.PercentageTrainingTrialsLabel.Text = 'Percentage Training Trials';
            app.first_lick_no_reward = uicheckbox(app.Panel_TDC);
            app.first_lick_no_reward.Text = 'First lick no reward';
            app.TCSALickTimeWindowsLabel = uilabel(app.Panel_TDC);
            app.TCSALickTimeWindowsLabel.Text = 'TCSA Lick Time Window (s)';
            app.tcsa_lick_time_window = uieditfield(app.Panel_TDC, 'numeric');
            app.tcsa_lick_time_window.Value = 0.5;
            app.Panel_DM = uipanel(app.figure1);
            app.ButtonGroup_DM = uibuttongroup(app.Panel_DM);
            app.Light = uiradiobutton(app.ButtonGroup_DM);
            app.Light.Text = 'Light';
            app.Light.Value = true;
            app.Tone = uiradiobutton(app.ButtonGroup_DM);
            app.Tone.Text = {'Tone'; ''};
            app.Noise = uiradiobutton(app.ButtonGroup_DM);
            app.Noise.Text = 'Noise';
            app.HzLabel = uilabel(app.ButtonGroup_DM);
            app.HzLabel.Text = '(Hz)';
            app.DM_tone_freq = uieditfield(app.ButtonGroup_DM, 'numeric');
            app.BrightnessVolumeLabel = uilabel(app.ButtonGroup_DM);
            app.BrightnessVolumeLabel.Text = 'Brightness / Volume';
            app.DM_brightness_volume = uieditfield(app.ButtonGroup_DM, 'numeric');
            app.DM_brightness_volume.ValueChangedFcn = createCallbackFcn(app, @DM_brightness_volumeValueChanged, true);
            app.DM_brightness_volume.Value = 4;
            app.RateDiscrimination = uicheckbox(app.Panel_DM);
            app.RateDiscrimination.ValueChangedFcn = createCallbackFcn(app, @RateDiscriminationValueChanged, true);
            app.RateDiscrimination.Text = 'Rate Discrimination';
            app.light2 = uicheckbox(app.Panel_DM);
            app.light2.ValueChangedFcn = createCallbackFcn(app, @light2_Callback, true);
            app.light2.Text = '2 Panel Light';
            app.light8 = uicheckbox(app.Panel_DM);
            app.light8.ValueChangedFcn = createCallbackFcn(app, @light8_Callback, true);
            app.light8.Text = '6 panel Light';
            app.no_test_punishment = uicheckbox(app.Panel_DM);
            app.no_test_punishment.Text = 'No Test Punish';
            app.SideDiscrimination = uicheckbox(app.Panel_DM);
            app.SideDiscrimination.ValueChangedFcn = createCallbackFcn(app, @SideDiscriminationValueChanged, true);
            app.SideDiscrimination.Text = 'Side Discrimination';
            app.HighRateHzLabel = uilabel(app.Panel_DM);
            app.HighRateHzLabel.Text = 'High Rate (Hz)';
            app.high_rate = uieditfield(app.Panel_DM, 'numeric');
            app.high_rate.Value = 1;
            app.LowRateHzLabel = uilabel(app.Panel_DM);
            app.LowRateHzLabel.Text = 'Low Rate (Hz)';
            app.low_rate = uieditfield(app.Panel_DM, 'numeric');
            app.low_rate.Value = 10;
            app.DurationsLabel = uilabel(app.Panel_DM);
            app.DurationsLabel.Text = 'Duration (s)';
            app.stim_duration_rate_disc = uieditfield(app.Panel_DM, 'numeric');
            app.stim_duration_rate_disc.Value = 5;
            app.higher_rate_correct = uicheckbox(app.Panel_DM);
            app.higher_rate_correct.Text = 'Higher Rate Correct';
            app.Stim_durationsLabel = uilabel(app.Panel_DM);
            app.Stim_durationsLabel.Text = 'Stim_duration (s)';
            app.stim_duration = uieditfield(app.Panel_DM, 'numeric');
            app.stim_duration.Value = 0.1;
            app.Stim_cycle_durationsLabel = uilabel(app.Panel_DM);
            app.Stim_cycle_durationsLabel.Text = 'Stim_cycle_duration (s)';
            app.stim_cycle_duration = uieditfield(app.Panel_DM, 'numeric');
            app.stim_cycle_duration.Value = 0.25;
            app.NumberofstimcyclesLabel = uilabel(app.Panel_DM);
            app.NumberofstimcyclesLabel.Text = 'Number of stim cycles';
            app.number_of_stim_cycles = uieditfield(app.Panel_DM, 'numeric');
            app.number_of_stim_cycles.Value = 100;
            app.two_rates_only = uicheckbox(app.Panel_DM);
            app.two_rates_only.ValueChangedFcn = createCallbackFcn(app, @two_rates_onlyValueChanged, true);
            app.two_rates_only.Text = '2 Rates Only';
            app.mixed_rates = uicheckbox(app.Panel_DM);
            app.mixed_rates.ValueChangedFcn = createCallbackFcn(app, @mixed_ratesValueChanged, true);
            app.mixed_rates.Text = 'Mixed Rates';
            app.no_test_punishment_2 = uicheckbox(app.Panel_DM);
            app.no_test_punishment_2.Text = 'No Test Punish';
            app.MaxRatioLabel = uilabel(app.Panel_DM);
            app.MaxRatioLabel.Text = 'Max Ratio';
            app.max_ratio = uieditfield(app.Panel_DM, 'numeric');
            app.max_ratio.Value = 4;
            app.MixedRatesStepsDropDownLabel = uilabel(app.Panel_DM);
            app.MixedRatesStepsDropDownLabel.Text = 'Mixed Rates Steps';
            app.mixed_rates_steps = uidropdown(app.Panel_DM);
            app.mixed_rates_steps.Items = {'3', '4', '5', '6', ''};
            app.mixed_rates_steps.ItemsData = {'3', '4', '5', '6'};
            app.mixed_rates_steps.Value = '3';
            app.single_panel = uicheckbox(app.Panel_DM);
            app.single_panel.Text = 'Single Panel';
            app.Panel_General = uipanel(app.figure1);
            app.ExperimentDurationsLabel = uilabel(app.Panel_General);
            app.ExperimentDurationsLabel.Text = 'Experiment Duration (s)';
            app.ex_duration = uieditfield(app.Panel_General, 'numeric');
            app.ex_duration.Value = 900;
            app.State1TimeoutsLabel = uilabel(app.Panel_General);
            app.State1TimeoutsLabel.Text = 'State1 Timeout (s)';
            app.state1_timeout = uieditfield(app.Panel_General, 'numeric');
            app.state1_timeout.Value = 30;
            app.State3TimeoutsLabel = uilabel(app.Panel_General);
            app.State3TimeoutsLabel.Text = 'State3 Timeout (s)';
            app.state3_timeout = uieditfield(app.Panel_General, 'numeric');
            app.state3_timeout.Value = 30;
            app.NumberofLicksRequiredLabel = uilabel(app.Panel_General);
            app.NumberofLicksRequiredLabel.Text = 'Number of Licks Required';
            app.lick_count_requirement = uieditfield(app.Panel_General, 'numeric');
            app.lick_count_requirement.Value = 1;
            app.LickTimeRequirementLabel = uilabel(app.Panel_General);
            app.LickTimeRequirementLabel.Text = 'Lick Time Requirement';
            app.lick_time_requirement = uieditfield(app.Panel_General, 'numeric');
            app.lick_time_requirement.Value = 1;
            app.text19_2 = uilabel(app.Panel_General);
            app.text19_2.Text = 'General Controls';
            app.Waitbeforestate2or4Label = uilabel(app.Panel_General);
            app.Waitbeforestate2or4Label.Text = 'Wait before state 2 or 4';
            app.wait24 = uieditfield(app.Panel_General, 'numeric');
            app.wait24.Value = 1.5;
            app.timeout_punishment = uicheckbox(app.Panel_General);
            app.timeout_punishment.Text = 'Timeout Punishment';
            app.punishment_active = uicheckbox(app.Panel_General);
            app.punishment_active.Text = 'Wrong Lick Punishment';
            app.AirPuffmsLabel = uilabel(app.Panel_General);
            app.AirPuffmsLabel.Text = 'Air Puff (ms)';
            app.airpuff_duration = uieditfield(app.Panel_General, 'numeric');
            app.PunishmentToneTimesLabel = uilabel(app.Panel_General);
            app.PunishmentToneTimesLabel.Text = 'Punishment Tone Time (s)';
            app.punishment_tone_time = uieditfield(app.Panel_General, 'numeric');
            app.punishment_tone_time.Value = 1;
            app.PunishmentRestTimesLabel = uilabel(app.Panel_General);
            app.PunishmentRestTimesLabel.Text = 'Punishment Rest Time (s)';
            app.punishment_rest_time = uieditfield(app.Panel_General, 'numeric');
            app.punishment_rest_time.Value = 2;
            app.BiasCorroftestsLabel = uilabel(app.Panel_General);
            app.BiasCorroftestsLabel.Text = 'Bias Corr # of tests';
            app.bias_buffer = uieditfield(app.Panel_General, 'numeric');
            app.bias_buffer.Value = 1;
            app.BiasCorrdiffLabel = uilabel(app.Panel_General);
            app.BiasCorrdiffLabel.Text = 'Bias Corr  diff';
            app.bias_diff = uieditfield(app.Panel_General, 'numeric');
            app.bias_diff.Value = 1;
            app.retrial_mode = uicheckbox(app.Panel_General);
            app.retrial_mode.ValueChangedFcn = createCallbackFcn(app, @retrial_mode_Callback, true);
            app.retrial_mode.Text = 'Retrial Mode';
            app.training_reward = uicheckbox(app.Panel_General);
            app.training_reward.ValueChangedFcn = createCallbackFcn(app, @training_reward_Callback, true);
            app.training_reward.Text = 'No Retrial Reward';
            app.free_reward = uicheckbox(app.Panel_General);
            app.free_reward.Text = 'Free Reward';
            app.biason = uicheckbox(app.Panel_General);
            app.biason.Text = 'Bias Correction Active';
            app.LoadSettingsButton = uibutton(app.figure1, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.figure1, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.text29_4 = uilabel(app.figure1);
            app.text29_4.Text = 'Left correct';
            app.text29_7 = uilabel(app.figure1);
            app.text29_7.Text = 'Right correct';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Decision_MakingCopy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end